classdef AudioStreamerTest < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                  matlab.ui.Figure
      signalLamp                matlab.ui.control.Lamp
      signalLampLabel           matlab.ui.control.Label
      StatusLabel               matlab.ui.control.Label
      StatusLamp                matlab.ui.control.Lamp
      DeviceEditField           matlab.ui.control.EditField
      DeviceEditFieldLabel      matlab.ui.control.Label
      SignalEditField           matlab.ui.control.EditField
      SignalEditFieldLabel      matlab.ui.control.Label
      BackgroundEditField       matlab.ui.control.EditField
      BackgroundEditFieldLabel  matlab.ui.control.Label
      WaverStimField            matlab.ui.control.EditField
      PlayButton                matlab.ui.control.Button
      CaregiverStimField        matlab.ui.control.EditField
      DoItButton                matlab.ui.control.Button
      banner                    matlab.ui.control.Image
      ParticipantCheckBox       matlab.ui.control.CheckBox
      WaverCheckBox             matlab.ui.control.CheckBox
      CaregiverCheckBox         matlab.ui.control.CheckBox
      StopButton                matlab.ui.control.Button
   end

   
   properties (Access = private)
      hstreamer % Description
      hmixer % Description
      statusTimer % Description
      status = 'stopped';
      timeOut = 5;
      numTimerCalls = 0;
      lampOn = false % Description
      triggered = false;
      triggerPending = false;
   end
   
   methods (Access = private)
      
      function ShowAudioConfig(app)
         config = app.hstreamer.GetConfig();

         app.DeviceEditField.Value = char(config.device);
         app.CaregiverStimField.Value = char(config.caregiver);
         app.WaverStimField.Value = char(config.waver);
         app.BackgroundEditField.Value = char(config.background);
         app.SignalEditField.Value = char(config.signal);

         app.BackgroundEditFieldLabel.Enable = true;
         app.SignalEditFieldLabel.Enable = true;

         app.PlayButton.Enable = true;
      end

      %--------------------------------------------------------------------
      function TimerCallback(app, ~, ~)

         app.numTimerCalls = app.numTimerCalls + 1;
         newStatus = char(app.hstreamer.GetStatus());

         if isequal(newStatus, 'error')
            app.statusTimer.stop();
            app.status = 'error';
            app.SetLampState(app.status, 'error');
            errMsg = char(app.hstreamer.GetErrorMessage());
            msgbox(['Audio stream error: ' errMsg], 'Audio Streamer', 'error');
            return;
         elseif isequal(newStatus, 'stopped') && isequal(app.status, 'starting')
            if app.numTimerCalls * app.statusTimer.Period > app.timeOut
               app.statusTimer.stop();
               app.status = 'timed out';
               app.SetLampState(app.status, 'error');
               return;
            end
         elseif isequal(newStatus, 'running')
            if isequal(app.status, 'starting')
               app.StopButton.Enable = true;
               app.DoItButton.Enable = true;
            end
            app.status = 'running';
            app.SetLampState(app.status, 'toggle');
         elseif isequal(newStatus, 'stopped')
            app.status = 'stopped';
            app.SetLampState(app.status, 'off');
            app.statusTimer.stop();
         end

         triggerStatus = app.hstreamer.IsTriggered();
         color = [0 0.2 0];
         if triggerStatus
            color = [0 1 0];
         end
         app.signalLamp.Color = color;

         if app.triggerPending
            if triggerStatus
               app.triggered = true;   
               app.triggerPending = false;
            end
         elseif app.triggered
            if ~triggerStatus
               app.triggered = false;
               app.DoItButton.Enable = true;
            end
         end
      end
      
      %--------------------------------------------------------------------
      function TimerStopFcn(app, ~, ~)
         app.PlayButton.Visible = true;
      end

      %--------------------------------------------------------------------
      function SetLampState(app, msg, state)
         
         if isequal(state, 'on')
            app.lampOn = true;
         elseif isequal(state, 'off')
            app.lampOn = false;
         elseif isequal(state, 'toggle')
            app.lampOn = ~app.lampOn;
         end

         color = [0 0.2 0];
         if isequal(state, 'error')
            color = [1 0 0];
         elseif app.lampOn
            color = [0 1 0];
         end
         app.StatusLabel.Text = msg;
         app.StatusLamp.Color = color;
      end
      
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, streamer, mixer)
         app.hstreamer = streamer;
         app.hmixer = mixer;

         app.CaregiverCheckBox.Value = false;
         app.WaverCheckBox.Value = false;
         app.ParticipantCheckBox.Value = false;
         
         app.CaregiverCheckBox.Enable = false;
         app.WaverCheckBox.Enable = false;
         app.ParticipantCheckBox.Enable = false;
         app.BackgroundEditFieldLabel.Enable = false;
         app.SignalEditFieldLabel.Enable = false;
         
         app.PlayButton.Enable = false;
         app.DoItButton.Enable = false;

         % Restore figure position
         origin = getpref('AudioStreamerTest', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end

         app.statusTimer = timer(...
            'TimerFcn', @app.TimerCallback, ...
            'StopFcn', @app.TimerStopFcn, ...
            'Period', 0.5, ...
            'ExecutionMode', 'fixedRate', ...
            'TasksToExecute', Inf ...
            );

         if app.hstreamer.IsOpen
            app.ShowAudioConfig();
         end

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         pos = app.UIFigure.Position;
         setpref('AudioStreamerTest', 'Position', pos(1:2));
         uiresume(app.UIFigure);
         delete(app)
      end

      % Button pushed function: DoItButton
      function DoItButtonPushed(app, event)
         app.hstreamer.Trigger(true);
         app.DoItButton.Enable = false;
         app.triggerPending = true;
         app.triggered = false;
      end

      % Button pushed function: PlayButton
      function PlayButtonPushed(app, event)
         app.PlayButton.Visible = false;
         app.StopButton.Enable = false;
%          app.hstreamer.Start();
         app.hmixer.MuteAudioStream(false);

         app.CaregiverCheckBox.Value = false;
         app.WaverCheckBox.Value = false;
         app.ParticipantCheckBox.Value = false;

         app.CaregiverCheckBox.Enable = true;
         app.WaverCheckBox.Enable = true;
         app.ParticipantCheckBox.Enable = true;

         app.numTimerCalls = 0;
         app.triggerPending = false;
         app.triggered = false;
         app.status = 'starting';
         app.SetLampState(app.status, 'on');
         app.statusTimer.start();
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.CaregiverCheckBox.Enable = false;
         app.WaverCheckBox.Enable = false;
         app.ParticipantCheckBox.Enable = false;

         app.statusTimer.stop();

         app.hmixer.MuteAudioStream(true);
         app.hmixer.ConnectMonitor(-1);
%          app.hstreamer.Stop();
      end

      % Value changed function: CaregiverCheckBox
      function CaregiverCheckBoxValueChanged(app, event)
         app.WaverCheckBox.Value = false;
         app.ParticipantCheckBox.Value = false;
         value = -1;
         if app.CaregiverCheckBox.Value, value = 0; end
         app.hmixer.ConnectMonitor(value);
      end

      % Value changed function: WaverCheckBox
      function WaverCheckBoxValueChanged(app, event)
         app.CaregiverCheckBox.Value = false;
         app.ParticipantCheckBox.Value = false;         
         value = -1;
         if app.WaverCheckBox.Value, value = 1; end
         app.hmixer.ConnectMonitor(value);
      end

      % Value changed function: ParticipantCheckBox
      function ParticipantCheckBoxValueChanged(app, event)
         app.CaregiverCheckBox.Value = false;
         app.WaverCheckBox.Value = false;
         value = -1;
         if app.ParticipantCheckBox.Value, value = 2; end
         app.hmixer.ConnectMonitor(value);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Audio Test';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Icon = 'sign_stop.png';
         app.StopButton.Text = {''; ''};
         app.CaregiverCheckBox = uicheckbox(app.UIFigure);
         app.CaregiverCheckBox.ValueChangedFcn = createCallbackFcn(app, @CaregiverCheckBoxValueChanged, true);
         app.CaregiverCheckBox.Text = 'Caregiver';
         app.WaverCheckBox = uicheckbox(app.UIFigure);
         app.WaverCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaverCheckBoxValueChanged, true);
         app.WaverCheckBox.Text = 'Waver';
         app.ParticipantCheckBox = uicheckbox(app.UIFigure);
         app.ParticipantCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantCheckBoxValueChanged, true);
         app.ParticipantCheckBox.Text = 'Participant';
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.DoItButton = uibutton(app.UIFigure, 'push');
         app.DoItButton.ButtonPushedFcn = createCallbackFcn(app, @DoItButtonPushed, true);
         app.DoItButton.Icon = 'arrow_right_green.png';
         app.DoItButton.Text = 'Do it';
         app.CaregiverStimField = uieditfield(app.UIFigure, 'text');
         app.CaregiverStimField.Editable = 'off';
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
         app.PlayButton.Icon = 'media_play_green.png';
         app.PlayButton.Text = 'Play';
         app.WaverStimField = uieditfield(app.UIFigure, 'text');
         app.WaverStimField.Editable = 'off';
         app.BackgroundEditFieldLabel = uilabel(app.UIFigure);
         app.BackgroundEditFieldLabel.Text = 'Background';
         app.BackgroundEditField = uieditfield(app.UIFigure, 'text');
         app.BackgroundEditField.Editable = 'off';
         app.SignalEditFieldLabel = uilabel(app.UIFigure);
         app.SignalEditFieldLabel.Text = 'Signal';
         app.SignalEditField = uieditfield(app.UIFigure, 'text');
         app.SignalEditField.Editable = 'off';
         app.DeviceEditFieldLabel = uilabel(app.UIFigure);
         app.DeviceEditFieldLabel.Text = 'Device';
         app.DeviceEditField = uieditfield(app.UIFigure, 'text');
         app.DeviceEditField.Editable = 'off';
         app.StatusLamp = uilamp(app.UIFigure);
         app.StatusLabel = uilabel(app.UIFigure);
         app.StatusLabel.Text = 'waiting';
         app.signalLampLabel = uilabel(app.UIFigure);
         app.signalLampLabel.Text = 'signal';
         app.signalLamp = uilamp(app.UIFigure);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = AudioStreamerTest(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end