classdef Mixer < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure        matlab.ui.Figure
      Button          matlab.ui.control.StateButton
      CaregiverPanel  matlab.ui.container.Panel
      Slider          matlab.ui.control.Slider
      SliderLabel     matlab.ui.control.Label
   end

   
   properties (Access = private)
      baseURL = "http://169.254.128.32/datastore/";
   end
   
   methods (Access = public)
      
      function success = IsConnected(app)
         success = false;
         try
            result = webread(app.baseURL);
            success = ~isempty(result);
         catch
         end
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         % Restore figure position
         origin = getpref('Mixer', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end         
         
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         pos = app.UIFigure.Position;
         setpref('Mixer', 'Position', pos(1:2));

         delete(app)         
      end

      % Value changing function: Slider
      function SliderValueChanging(app, event)
         changingValue = event.Value;
         webwrite(app.baseURL + "mix/chan/3/matrix/fader", sprintf('json={"value":%.2f}', 10.^(changingValue/20)));

      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Mixer';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.CaregiverPanel = uipanel(app.UIFigure);
         app.CaregiverPanel.Title = 'Caregiver';
         app.SliderLabel = uilabel(app.CaregiverPanel);
         app.SliderLabel.Text = 'Slider';
         app.Slider = uislider(app.CaregiverPanel);
         app.Slider.Limits = [-50 10];
         app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
         app.Button = uibutton(app.UIFigure, 'state');
         app.Button.Text = 'Button';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = Mixer

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end