classdef OPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SettingsMenu         matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        ToolsMenu            matlab.ui.container.Menu
        ToysMenu             matlab.ui.container.Menu
        ToysMenu_Test        matlab.ui.container.Menu
        VideoMenu            matlab.ui.container.Menu
        VideoMenu_Test       matlab.ui.container.Menu
        AudioMenu            matlab.ui.container.Menu
        AudioMenu_Test       matlab.ui.container.Menu
        AudioMenu_Settings   matlab.ui.container.Menu
        RestartMenu          matlab.ui.container.Menu
        CalibrateMenu        matlab.ui.container.Menu
        MixerMenu            matlab.ui.container.Menu
        RestartMenu_2        matlab.ui.container.Menu
        VoiceMenu            matlab.ui.container.Menu
        HelpMenu             matlab.ui.container.Menu
        OpenMenu             matlab.ui.container.Menu
        ManualCheckBox       matlab.ui.control.CheckBox
        TrainingLabel        matlab.ui.control.Label
        UseToy2CheckBox      matlab.ui.control.CheckBox
        UseVideoCheckBox     matlab.ui.control.CheckBox
        UseToy1CheckBox      matlab.ui.control.CheckBox
        PhaseProgressPanel   matlab.ui.container.Panel
        VoicePanel           matlab.ui.container.Panel
        VoicecuesLabel       matlab.ui.control.Label
        BounceButton         matlab.ui.control.Button
        TestButton           matlab.ui.control.Button
        TrainingButton       matlab.ui.control.Button
        PromptLabel          matlab.ui.control.Label
        TrialResultLabel     matlab.ui.control.Label
        PhaseProgressTable   matlab.ui.control.Table
        DataFileEditField    matlab.ui.control.EditField
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        LogPanel             matlab.ui.container.Panel
        LogTextArea          matlab.ui.control.TextArea
        ToolsPanel           matlab.ui.container.Panel
        MixerLamp            matlab.ui.control.Lamp
        MixerLampLabel       matlab.ui.control.Label
        AudioLamp            matlab.ui.control.Lamp
        AudioLampLabel       matlab.ui.control.Label
        VideoLamp            matlab.ui.control.Lamp
        VideoLampLabel       matlab.ui.control.Label
        ToysLamp             matlab.ui.control.Lamp
        ToysLampLabel        matlab.ui.control.Label
        PhaseTable           matlab.ui.control.Table
        ManualPanel          matlab.ui.container.Panel
        ValueEditField       matlab.ui.control.NumericEditField
        ValueEditFieldLabel  matlab.ui.control.Label
        manualAxes           matlab.ui.control.UIAxes
    end


   properties (Access = public)
      EndFcn = [];
      SynthFcn = [];
      RootFolder = [];
      CalPath = [];
      DataFile = [];
      Config = [];
      MeasurementType = '';
      VariableName = '';
      Ear = 'Both';
      DefaultTrainingValue = NaN;
      DefaultTestValues = [];
      CornerFrequencies = [];
   end

   properties (Access = private)
      hOntrak = [];
      hStreamer = [];
      hVLC = [];
      hMixer = [];
      hVoice = [];

      errorColor = [1 0 0];
      successColor = [0 1 0];

      isRunning = false;
      trialIsActive = false;
      rewardIsActive = false;
      haveResponse = false;
      responseTime = -1;

      currentAudioValue = NaN;

      rewardTimer;
      availableRewards = {};
      rewardDurations = [];
      rewardIndex = -1;

      trialTimer;
      trialStartTime;

      testDescription;
      settings;
      currentPhase = OPP_Phase();
      hardware;
      voice;

      logText = '';
      settingsAreDirty = false;
      hardwareStatus = struct('ontrak', false, 'vlc', false, 'audio', false, 'mixer', false, 'voice', false);

      phasePanelColor
      phaseData = []
      summaryData = []
      version = 'v1.3';
      activeRewards
      bouncedBackFrom = 0
      trainingCheck = false;

      debug;
      stopSimulation;
      ResourceFolder = 'C:\EPL'
      LogPath = '';

      manualMode = false; 
      manualValues = [];
      manualData
   end

   methods (Access = private)

      function SetDebugMode(app)
         app.debug.simulateOntrak = false;
         app.debug.simulation = 'off'; % {'off', 'trial', 'run' }
         app.debug.showStim = false;
      end

      %--------------------------------------------------------------------
      function InitializeDataFile(app)
         app.DataFileEditField.Value = app.DataFile + ".mat";
         info.date = char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS'));
         info.version = app.version;
         save(app.DataFileEditField.Value, 'info');

         if ~isempty(app.Config)
            config = app.Config;
            save(app.DataFileEditField.Value, 'config', '-append');
         end

      end

      %--------------------------------------------------------------------
      function SaveData(app)
         if app.manualMode
            m = matfile(app.DataFileEditField.Value);
            if isfield(m, 'manual')
               manual = m.manual;
               manual(end+1) = app.manualData;
            else
               manual = app.manualData;
            end
            save(app.DataFileEditField.Value, 'manual', '-append');
         else
            data = app.currentPhase.Data;
            data(end).hasSignal = data(end).hasSignal(1:length(data(end).response));
            app.phaseData(end) = data;
            data = app.phaseData;
            summary = app.summaryData;
            save(app.DataFileEditField.Value, 'data', 'summary', '-append');
         end

         app.FlushLog();
         app.AddLogEntry('Data saved');
      end

      %--------------------------------------------------------------------
      function InitializeLog(app)
         dt = datetime('now');
         dt.Format = 'yyyMMdd-HHmmss';
         app.LogPath = fullfile(app.ResourceFolder, 'Logs', sprintf('OPP-%s.log', string(dt)));
         app.logText = '';
         app.AddLogEntry(['OPP ' app.version ' started...']);
      end

      %--------------------------------------------------------------------
      function AddLogEntry(app, varargin)
         for k = 1:length(varargin)
            prefix = '';
            if k == 1
               app.logText = [app.logText char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS')) '   '];
               prefix = [char(datetime('now', 'Format', 'HH:mm:ss')) '  '];
            end
            app.logText = [app.logText varargin{k} newline];
            app.LogTextArea.Value{end+1} = [prefix varargin{k}];
         end
         app.LogTextArea.scroll('bottom');

         if length(app.logText) > 10000
            app.FlushLog();
         end
      end

      %--------------------------------------------------------------------
      function FlushLog(app)
         if ~isempty(app.logText)
            fp = fopen(app.LogPath, 'at');
            fprintf(fp, '%s', app.logText);
            fclose(fp);
            app.logText = '';
         end
      end

      %--------------------------------------------------------------------
      function RestoreSettings(app)
         app.hardware = getsharedpref(mfilename, 'HardwareSettings', app.DefaultHardwareSettings());
         app.voice = getsharedpref(mfilename, 'VoiceSettings', app.DefaultVoiceSettings());

         filePath = fullfile(app.ResourceFolder, 'OppSettings.mat');
         if ~exist(filePath, 'file')
            app.settings = app.DefaultSettings();
         else
            s = load(filePath, 'settings');
            app.settings = s.settings;
         end

         app.VoicePanel.Visible = app.voice.useVoice;

         app.activeRewards = getsharedpref(mfilename, 'ActiveRewards', {'toy1','toy2','video'});
         app.UseToy1CheckBox.Value = contains('toy1', app.activeRewards);
         app.UseToy2CheckBox.Value = contains('toy2', app.activeRewards);
         app.UseVideoCheckBox.Value = contains('video', app.activeRewards);

         stim = getsharedpref(mfilename, [app.MeasurementType '_Stim'], []);
         if ~isempty(stim) && ~strcmpi(app.MeasurementType, 'sweepmasker')
            app.settings.stimulus.trainingValue = stim.trainingValue;
            app.settings.stimulus.easyValue = stim.easyValue;
            app.settings.stimulus.testValues = stim.testValues;
         else
            app.settings.stimulus.trainingValue = app.DefaultTrainingValue;
            app.settings.stimulus.easyValue = app.DefaultTrainingValue;
            app.settings.stimulus.testValues = app.DefaultTestValues;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeHardware(app)

         d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate', 'on');
         drawnow;

         d.Message = 'Initializing Ontrak...';
         app.hardwareStatus.ontrak = app.InitializeOntrak();
         drawnow;

         d.Message = 'Initializing video...';
         app.hardwareStatus.vlc = app.InitializeVLC();
         drawnow;

         d.Message = 'Initializing audio streamer...';
         app.hardwareStatus.audio = app.InitializeAudioStreamer();
         drawnow;

         d.Message = 'Initializing mixer...';
         app.hardwareStatus.mixer = app.InitializeMixer(d);
         drawnow;

         d.Message = 'Initializing voice...';
         app.InitializeVoice(d);
         drawnow;

         delete(d);

         success = app.hardwareStatus.ontrak && app.hardwareStatus.vlc && app.hardwareStatus.audio && app.hardwareStatus.mixer;
      end

      %--------------------------------------------------------------------
      function success = InitializeOntrak(app)
         success = false;

         if ~isempty(app.hOntrak)
            delete(app.hOntrak);
            app.hOntrak = [];
            app.AddLogEntry('Ontrak cleared.');
         end

         if ~app.settings.reward.useToys
            app.ToysLamp.Color = [0 0.25 0];
            success = true;
            return;
         end

         try
            app.hOntrak = Ontrak(app.debug.simulateOntrak);
            app.hOntrak = app.hOntrak.Initialize();
            app.hOntrak.TurnOff();
            app.ToysLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('Ontrak initialized.');
         catch ex
            app.AddLogEntry(['***Failed to initialize Ontrak: ' ex.message]);
            app.ToysLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeAudioStreamer(app)
         success = false;
         try
            isLoaded = false;
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.AudioStreamer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile(fileparts(which(mfilename)), 'DotNet', 'Streamer', 'OppStreamer.ConfigApi.dll'));
            end

            app.hStreamer = OppStreamer.ConfigApi.ConfigApi;
            app.hStreamer.Initialize();

            isValid = app.hStreamer.IsOutputDeviceValid(app.hardware.outputDevice);
            if ~isValid
               error([app.hardware.outputDevice ' is not a valid device'])
            end

            isValid = app.hStreamer.IsMicDeviceValid(app.hardware.micDevice);
            if ~isValid
               error([app.hardware.micDevice ' is not a valid device'])
            end

            app.AudioLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('AudioStreamer initialized.');
         catch ex
            app.AddLogEntry(['***Failed to initialize AudioStreamer: ' ex.message]);
            app.AudioLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeVLC(app)
         success = false;

         if ~app.settings.reward.useVideo
            if ~isempty(app.hVLC)
               app.hVLC.quit();
               delete(app.hVLC);
               app.hVLC = [];
               app.AddLogEntry('VLC closed.');
            end
            app.VideoLamp.Color = [0 0.25 0];
            success = true;
            return;
         end

         try
            %             audioDevice = '--mmdevice-audio-device={0.0.0.00000000}.{93497dbf-9415-42e3-b629-61d9fd0b4c22}';
            audioDevice = '--mmdevice-audio-device={0.0.0.00000000}.{c0fc4fbd-1d60-4219-8784-8d0e41bb54ff}';

            args = { ...
               '--no-qt-bgcone', ...
               '--qt-continue=2', ...
               '--no-video-title-show', ...
               '--qt-auto-raise=0', ...
               audioDevice ...
               };

            app.hVLC = VLC(args{:});
            app.hVLC.Status();
            app.VideoLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('VLC initialized.');
         catch ex
            app.AddLogEntry('***Failed to initialize VLC', ex.message);
            app.hVLC = [];
            app.VideoLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeMixer(app, d)
         try
            success = false;
            isLoaded = false;
            d.Message = 'Initializing mixer: loading .NET assembly...';
            drawnow();
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.Mixer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile(fileparts(which(mfilename)), 'DotNet', 'Mixer', 'OPP.Mixer.dll'));
            end

            app.hMixer = OPP.Mixer.Mixer;

            d.Message = 'Initializing mixer: checking for device...';
            drawnow();
            
            % TODO: REPLACE MIXER
            app.hMixer.Open();
            success = app.hMixer.Initialize(app.hardware.motuAddress);
            if success
               app.MixerLamp.Color = app.successColor;
               app.AddLogEntry('Mixer initialized.');
            else
               app.AddLogEntry(['***Failed to initialize Mixer: ' char(app.hMixer.LastErrorMessage)]);
               app.MixerLamp.Color = app.errorColor;
            end
         catch ex
            app.AddLogEntry('***Failed to initialize Mixer', ex.message);
            app.MixerLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeVoice(app, d)
         try
            success = false;
            isLoaded = false;
            d.Message = 'Initializing voice: loading .NET assembly...';
            drawnow();
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'KLib.WindowsVoice');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile(fileparts(which(mfilename)), 'DotNet', 'Voice', 'KLib.WindowsVoice.dll'));
            end

            app.hVoice = KLib.WindowsVoice.WindowsVoice;

            success = true;
         catch ex
            app.AddLogEntry('***Failed to initialize Voice', ex.message);
         end
      end

      %--------------------------------------------------------------------
      function result = SetLaterality(app, ear)
         result = motu_set_laterality_monitor8(app.hardware.motuAddress, ear);
      end

      %--------------------------------------------------------------------
      function InitializePhases(app)
         nrows = length(app.settings.stimulus.testValues) + 2;
         data = cell(nrows, 8);
         rowNames = cell(nrows, 1);
         for k = 1:nrows
            rowNames{k} = num2str(k);
            if k == 1
               data{k, 1} = 'Training';
               data{k, 2} = app.settings.stimulus.trainingValue;
            elseif k == 2
               data{k, 1} = 'Easy';
               data{k, 2} = app.settings.stimulus.easyValue;
            else
               data{k, 1} = 'Test';
               data{k, 2} = app.settings.stimulus.testValues(k-2);
            end
         end

         buttonStyle = uistyle("BackgroundColor", 0.94 * [ 1 1 1], "HorizontalAlignment", 'center');
         buttonCells = [1:3; 8 8 8]';
         for k = 1:3
            data{k, 8} = 'Edit';
            addStyle(app.PhaseTable, buttonStyle, "cell", buttonCells)
         end

         app.PhaseTable.CellSelectionCallback = @app.PhaseTableCellSelection;
         app.PhaseTable.Data = data;
         app.PhaseTable.RowName = rowNames;

         cellStyle = uistyle("FontColor", [0 0.5 0], 'HorizontalAlignment', 'right');
         addStyle(app.PhaseTable, cellStyle, "column", 3);
         cellStyle = uistyle("FontColor", [0 0.5 0], 'HorizontalAlignment', 'center');
         addStyle(app.PhaseTable, cellStyle, "column", 4);
         cellStyle = uistyle("FontColor", [0.75 0 0], 'HorizontalAlignment', 'right');
         addStyle(app.PhaseTable, cellStyle, "column", 5);
         cellStyle = uistyle("FontColor", [0.75 0 0], 'HorizontalAlignment', 'center');
         addStyle(app.PhaseTable, cellStyle, "column", 6);
         cellStyle = uistyle("FontColor", [0 0 0], 'HorizontalAlignment', 'center');
         addStyle(app.PhaseTable, cellStyle, "column", 7);

         app.InitializeSummaryData();
         app.PhaseTable.Selection = [];

         app.InitializeManualMode();
         app.PlotManualData();
      end

      %--------------------------------------------------------------------
      function InitializeManualMode(app)
         app.manualValues = unique([ app.settings.stimulus.trainingValue app.settings.stimulus.easyValue app.settings.stimulus.testValues ], 'stable');
         app.ValueEditField.Value = app.manualValues(1);
      end
      
      %--------------------------------------------------------------------
      function InitializeSummaryData(app)
         nrows = length(app.settings.stimulus.testValues) + 2;
         D = struct('name', cell(nrows, 1), 'value', cell(nrows, 1), ...
            'hit', 0, ...
            'nsig', 0, ...
            'fa', 0, ...
            'nno', 0, ...
            'dprime', NaN ...
            );

         D(1).name = 'Training';
         D(1).value = app.settings.stimulus.trainingValue;
         D(2).name = 'Easy';
         D(2).value = app.settings.stimulus.easyValue;
         for k = 1:length(app.settings.stimulus.testValues)
            D(k + 2).name = 'Test';
            D(k + 2).value = app.settings.stimulus.testValues(k);
         end

         app.summaryData = D;
         app.UpdateSummaryData('');
         app.PlotSummaryData();
      end

      %--------------------------------------------------------------------
      function UpdateSummaryData(app, result)
         num = app.currentPhase.Number;
         if isequal(result, 'hit')
            app.summaryData(num).hit = app.summaryData(num).hit + 1;
            app.summaryData(num).nsig = app.summaryData(num).nsig + 1;
         elseif isequal(result, 'miss')
            app.summaryData(num).nsig = app.summaryData(num).nsig + 1;
         elseif isequal(result, 'false alarm')
            app.summaryData(num).fa = app.summaryData(num).fa + 1;
            app.summaryData(num).nno = app.summaryData(num).nno + 1;
         elseif isequal(result, 'withhold')
            app.summaryData(num).nno = app.summaryData(num).nno + 1;
         end

         if ~isempty(num) && app.summaryData(num).nsig > 0 && app.summaryData(num).nno > 0
            app.summaryData(num).dprime = norminv(app.summaryData(num).hit / app.summaryData(num).nsig) - norminv(app.summaryData(num).fa / app.summaryData(num).nno);
         end

         data = cell(length(app.summaryData), 5);
         for k = 1:length(app.summaryData)
            if app.summaryData(k).nsig > 0
               data{k, 1} = sprintf('%d%%', round(100*app.summaryData(k).hit / app.summaryData(k).nsig));
               data{k, 2} = sprintf('%d / %d', app.summaryData(k).hit, app.summaryData(k).nsig);
            end
            if app.summaryData(k).nno > 0
               data{k, 3} = sprintf('%d%%', round(100*app.summaryData(k).fa / app.summaryData(k).nno));
               data{k, 4} = sprintf('%d / %d', app.summaryData(k).fa, app.summaryData(k).nno);
            end
            if ~isnan(app.summaryData(k).dprime)
               data{k, 5} = sprintf('%.3f', app.summaryData(k).dprime);
            end
         end
         app.PhaseTable.Data(:, 3:7) = data;
      end

      %--------------------------------------------------------------------
      function PlotSummaryData(app)
         values = [app.settings.stimulus.trainingValue app.settings.stimulus.easyValue app.settings.stimulus.testValues(:)'];
         x = 1:length(values);
         dprime = [app.summaryData.dprime];

         fig = figure(999);
         set(fig, 'Name', 'Summary', 'NumberTitle', 'off');

         hax = subplot(3, 1, [1 2]);
         cla;
         cmap = get(hax, 'ColorOrder');
         hold on;
         ifilt = isfinite(dprime);
         plot(x(ifilt), dprime(ifilt), 'o', 'Color', cmap(1,:), 'MarkerFaceColor', cmap(1,:));

         yline(1, ':');

         if ~any(ifilt)
            dmin = 0;
            dmax = 2;
         else
            dmin = min(0, min(dprime(ifilt), [], 'omitnan'));
            dmax = max(2, max(dprime(ifilt)));
         end
         dmax = dmin + (dmax - dmin) * 1.05;

         ifilt = ~isfinite(dprime) & ~isnan(dprime);
         if any(ifilt)
            plot(x(ifilt), dmax*ones(size(x(ifilt))), 'x', 'Color', cmap(1,:));
         end

         set(hax, 'XLim', [0.5 length(values)+0.5]);
         set(hax, 'XTick', x, 'XTickLabel', num2str(values(:)));
         set(hax, 'YLim', [dmin dmax]);

         xlabel(app.VariableName);
         ylabel('d-prime');

         hax = subplot(3, 1, 3);
         cla;
         set(hax, 'YLim', [0.5 length(values)+0.5], 'YDir', 'reverse');
         ylabel('Phase #');
         xlabel('History');
         hold on;
         if isempty(app.phaseData)
            set(hax, 'XLim', [0.5 length(values)+0.5]);
         else
            plot([app.phaseData.phaseNumber], 's-', 'Color', cmap(1, :), 'MarkerFaceColor', cmap(1,:));
            set(hax, 'XLim', [0.5 max(length(values), length([app.phaseData.phaseNumber]))+0.5]);
         end

      end

      %--------------------------------------------------------------------
      function SetVideoList(app)
         if isempty(app.hVLC) || ~app.hardwareStatus.vlc, return; end

         app.hVLC.clear();

         if ~isempty(app.settings.reward.videoPlaylist)
            for k = 1:length(app.settings.reward.videoPlaylist)
               app.hVLC.add(app.settings.reward.videoPlaylist{k});
            end
         end
      end

      %--------------------------------------------------------------------
      function SetAudioStream(app, isTraining, value)
         if isempty(app.SynthFcn) || value == app.currentAudioValue, return; end

         % app.hStreamer.Stop();
         % stopped = app.WaitForStreamState('stopped');
         % 
         % if ~stopped
         %    app.CheckForStreamError();
         %    return;
         % end

         Fs = 48000;
         Npts = round(Fs * app.settings.stimulus.ITI_s);
         zeroSignal = zeros(Npts, 1);

         app.hStreamer.SetConfig(app.hardware.outputDevice, Npts, app.hardware.micDevice, app.hardware.waverMicDevice);
         app.hStreamer.SetNumReps(app.settings.stimulus.repsPerTrial);

         [name, waveform] = app.SynthFcn(value, 'caregiver');

         yCaregiver = zeroSignal;
         yCaregiver(1:length(waveform)) = waveform;
         % app.hStreamer.SetSignal('caregiver', name, y);
         app.currentPhase = app.currentPhase.SetWaveformName('background2', name);

         [~, waveform] = app.SynthFcn(value, 'waver');
         yWaver = zeroSignal;
         yWaver(1:length(waveform)) = waveform;
         % app.hStreamer.SetSignal('waver', name, y);

         [name, waveform] = app.SynthFcn(value, 'background');
         yBackground = zeroSignal;
         yBackground(1:length(waveform)) = waveform;
         % app.hStreamer.SetSignal('background', name, y);
         app.currentPhase = app.currentPhase.SetWaveformName('background', name);

         [name, waveform] = app.SynthFcn(value, 'signal');
         ySignal = zeroSignal;
         ySignal(1:length(waveform)) = waveform;
         % app.hStreamer.SetSignal('signal', name, y);
         app.currentPhase = app.currentPhase.SetWaveformName('signal', name);

         app.hStreamer.SetStimulusSet('Test', yCaregiver, yWaver, yBackground, ySignal);
         app.SetTrainers();

         if ~app.hStreamer.IsStreaming
            app.hStreamer.Start();
            started = app.WaitForStreamState('running');
         else
            started = app.hStreamer.WaitForLatch(5);
         end

         if ~started
            app.CheckForStreamError();
            app.AddLogEntry('Failed to start audio stream.');
            return;
         end

         app.AddLogEntry('Audio stream updated.');
      end

      %--------------------------------------------------------------------
      function success = WaitForStreamState(app, state)
         success = false;

         startTime = tic();
         while toc(startTime) < 5
            isStreaming = app.hStreamer.IsStreaming;
            if isequal(state, 'running') && isStreaming
               success = true;
               break;
            end
            if isequal(state, 'stopped') && ~isStreaming
               success = true;
               break;
            end
            lastError = char(app.hStreamer.LastError);
            if isempty(lastError)
               success = false;
               break;
            end
            pause(0.1);
         end
      end

      %--------------------------------------------------------------------
      function CheckForStreamError(app)
         errMsg = char(app.hStreamer.LastError);

         if isempty(errMsg)
            app.AddLogEntry(['Audio stream error: ' errMsg]);
            msgbox(['Audio stream error: ' errMsg], 'Audio Streamer', 'error');
         end
      end

      %--------------------------------------------------------------------
      function SetTrainers(app)
         if isempty(app.SynthFcn), return; end

         Fs = 48000;
         Npts = round(Fs * app.settings.stimulus.ITI_s);
         zeroSignal = zeros(Npts, 1);

         value = app.settings.stimulus.trainingValue;

         [~, waveform] = app.SynthFcn(value, 'caregiver');
         yCaregiver = zeroSignal;
         yCaregiver(1:length(waveform)) = waveform;
         % app.hStreamer.SetTrainer('caregiver', y);

         [~, waveform] = app.SynthFcn(value, 'waver');
         yWaver = zeroSignal;
         yWaver(1:length(waveform)) = waveform;
         % app.hStreamer.SetTrainer('waver', y);

         [~, waveform] = app.SynthFcn(value, 'background');
         yBackground = zeroSignal;
         yBackground(1:length(waveform)) = waveform;
         % app.hStreamer.SetTrainer('background', y);

         [~, waveform] = app.SynthFcn(value, 'signal');
         ySignal = zeroSignal;
         ySignal(1:length(waveform)) = waveform;
         % app.hStreamer.SetTrainer('signal', y);

         app.hStreamer.SetStimulusSet('Training', yCaregiver, yWaver, yBackground, ySignal);
      end

      %--------------------------------------------------------------------
      function EndRun(app, message, clearResult)

         if nargin < 3
            clearResult = true;
         end

         app.isRunning = false;
         app.trainingCheck = false;
         app.TrainingLabel.Visible = 'off';
         app.TrainingLabel.Enable = 'on';
         app.trialIsActive = false;
         app.StartButton.Visible = true;
         app.StopButton.Visible = false;
         app.hMixer.MuteAudioStream(true);
         app.hMixer.EnableTesterStimulus(false);
         if clearResult
            app.TrialResultLabel.Text = '';
         end
         app.PromptLabel.Text = message;
         app.PromptLabel.FontColor = 'k';
         app.PhaseTable.Enable = 'on';
         app.ManualCheckBox.Enable = 'on';
         app.AddLogEntry('Run ended');
      end

      %--------------------------------------------------------------------
      function SetPhase(app, num, direction)
         app.HighlightPhaseTableRow(num);

         if num == 1
            value = app.settings.stimulus.trainingValue;
            phaseDef = app.settings.phaseDefs(1);
         elseif num == 2
            value = app.settings.stimulus.easyValue;
            phaseDef = app.settings.phaseDefs(2);
         else
            value = app.settings.stimulus.testValues(num - 2);
            phaseDef = app.settings.phaseDefs(3);
         end

         app.currentPhase = app.currentPhase.Initialize(num, value, phaseDef, direction);
         if isempty(app.phaseData)
            app.phaseData = app.currentPhase.Data;
         else
            if isempty(app.phaseData(end).correct)
               app.phaseData(end) = app.currentPhase.Data;
            else
               app.phaseData(end+1) = app.currentPhase.Data;
            end
         end

         app.AddLogEntry(sprintf('Set to phase #%d', num));

         if app.hardwareStatus.audio && app.hardwareStatus.mixer
            try
               app.StartButton.Enable = false;
               app.SetAudioStream(num==1, app.currentPhase.StimValue);
               app.StartButton.Enable = true;
            catch ex
               msgbox(ex.message, 'OPP', 'error');
               app.AddLogEntry(sprintf('Stream error: %s', ex.message));
            end
         end
      end

      %--------------------------------------------------------------------
      function EndPhase(app, result)
         app.SaveData();

         if isequal(result, 'passed')
            if app.currentPhase.Number < length(app.settings.stimulus.testValues) + 2
               message = ['Phase passed!!!' newline newline 'Press START to begin next phase'];

               if app.currentPhase.Number == 2 && app.bouncedBackFrom > 0
                  app.SetPhase(app.bouncedBackFrom, 'forward');
               else
                  app.SetPhase(app.currentPhase.Number + 1, 'forward');
               end
            else
               message = ['Phase passed!!!' newline newline 'No more phases remaining'];
            end
            app.EndRun(message);
         elseif app.currentPhase.Number < 3
            message = ['Phase failed, stopping run:' newline newline 'There is nowhere to jump back to'];
            app.EndRun(message);
         else
            message = ['Phase failed, bouncing back' newline newline 'Press START to begin Easy phase'];
            app.bouncedBackFrom = app.currentPhase.Number;
            app.SetPhase(2, 'bounce');
            app.EndRun(message, false);
         end
      end

      %--------------------------------------------------------------------
      function Speak(app, text)
         y = app.hVoice.Render(text);
         app.hStreamer.SendTTS(single(y));
      end
      %--------------------------------------------------------------------
      function StartTrial(app)
         app.trialIsActive = true;
         app.haveResponse = false;
         app.responseTime = -1;

         if app.manualMode
            app.manualData.Value(end+1) = app.ValueEditField.Value;
            app.manualData.Result(end+1) = NaN;
         end

         if app.voice.useVoice
            app.Speak(app.voice.trialStart);
         end

         [~, iActive] = intersect(lower(app.availableRewards), app.activeRewards);
         app.rewardIndex = iActive(randi(length(iActive)));

         app.hStreamer.Trigger(app.currentPhase.CurrentTrialHasSignal() > 0 || app.trainingCheck || app.manualMode);

         if app.debug.showStim
            fprintf('Trial %d: %s\n', app.currentPhase.GetNumberOfTrials());
         end

         while ~app.hStreamer.IsTrialActive()
            pause(0.1);
         end
         app.trialStartTime = tic();

         app.UIFigure.Color = 'r';
         app.PhaseProgressPanel.BackgroundColor = 'r';
         app.TrialResultLabel.Text = '';
         app.PromptLabel.Text = 'Trial started...';
         app.PromptLabel.FontColor = 'w';
         drawnow;
         app.trialTimer.StartDelay = app.settings.stimulus.trialDuration_s;
         app.trialTimer.start();
      end

      %--------------------------------------------------------------------
      function result = EndTrial(app)

         if app.manualMode
            result = 'continue';
            trialResult = 'miss';
            if app.haveResponse
               trialResult = 'hit';
            end

            app.manualData.Result(end) = isequal(trialResult, 'hit');
            app.AddLogEntry(sprintf('MANUAL: %s', trialResult));
            app.PlotManualData();

         elseif ~app.trainingCheck
            [app.currentPhase, result, message] = ...
               app.currentPhase.ProcessResult(...
               app.haveResponse, ...
               app.responseTime, ...
               app.availableRewards{app.rewardIndex} ...
               );

            app.PhaseProgressTable.Data = app.currentPhase.GetTableData();
            app.UpdateSummaryData(app.currentPhase.TrialResult);
            app.PlotSummaryData();
            app.AddLogEntry(sprintf('Trial %d: %s',  app.currentPhase.GetNumberOfTrials(), app.currentPhase.TrialResult));
         else
            result = 'continue';
            trialResult = 'miss';
            if app.haveResponse
               trialResult = 'hit';
            end

            app.AddLogEntry(sprintf('CHECK: %s', trialResult));
         end

         drawnow;

         app.SetFocus();
         if isequal(result, 'continue')
            app.PromptLabel.Text = "press ENTER to start trial";
            app.PromptLabel.FontColor = 'k';
         else
            app.AddLogEntry(['Phase ended: ' message]);
            app.EndPhase(result);
         end
      end

      %--------------------------------------------------------------------
      function ProcessResponse(app)
         stop(app.trialTimer);
         app.trialIsActive = false;
         app.UIFigure.Color = 0.94 * [1 1 1];
         app.PhaseProgressPanel.BackgroundColor = app.phasePanelColor;

         if app.currentPhase.CurrentTrialHasSignal() || app.trainingCheck || app.manualMode
            msg = 'HIT (signal)';
            color = [0 0.75 0];
            if app.voice.useVoice
               app.Speak(app.voice.hit);
            end
            app.AdministerReward();
         else
            msg = 'FALSE ALARM (no signal)';
            color = [0.75 0 0];
            app.PromptLabel.Text = '';
            if app.voice.useVoice
               app.Speak(app.voice.falseAlarm);
            end
            app.EndTrial();
         end

         app.TrialResultLabel.Text = msg;
         app.TrialResultLabel.FontColor = color;
         app.PhaseProgressPanel.BackgroundColor = app.phasePanelColor;
         drawnow;
      end

      %--------------------------------------------------------------------
      function AdministerReward(app)
         reward = app.availableRewards{app.rewardIndex};
         if isequal(reward, 'Toy1')
            app.hOntrak.TurnOn(true, false, true);
         elseif isequal(reward, 'Toy2')
            app.hOntrak.TurnOn(false, true, true);
         elseif isequal(reward, 'Video')
            %             app.hMixer.SetChannelProperty('participant', 'mute', true);
            app.hVLC.playSpecial();
         end
         app.rewardIsActive = true;
         app.rewardTimer.StartDelay = app.rewardDurations(app.rewardIndex);
         app.rewardTimer.start();
         app.PromptLabel.Text = sprintf('playing %s...', reward);
         app.PromptLabel.FontColor = 'k';
         drawnow;
      end

      %--------------------------------------------------------------------
      function TrialTimerCallback(app, ~, ~)
         if ~app.haveResponse
            endTrial = true;
            app.trialIsActive = false;
            app.UIFigure.Color = 0.94 * [1 1 1];
            app.PhaseProgressPanel.BackgroundColor = app.phasePanelColor;

            if app.currentPhase.CurrentTrialHasSignal() || app.trainingCheck || app.manualMode
               msg = 'MISS (signal)';
               color = [0.75 0 0];
               if app.voice.useVoice
                  app.Speak(app.voice.miss);
               end
               if app.currentPhase.MustRespond() || app.manualMode
                  app.PromptLabel.Text = '';
               else
                  app.AdministerReward();
                  endTrial = false;
               end
            else
               msg = 'WITHHOLD (no signal)';
               color = [0 0.75 0];
               app.PromptLabel.Text = '';
               if app.voice.useVoice
                  app.Speak(app.voice.withhold);
               end
            end

            app.TrialResultLabel.Text = msg;
            app.TrialResultLabel.FontColor = color;
            app.PhaseProgressPanel.BackgroundColor = app.phasePanelColor;
            drawnow;
            if endTrial
               app.PromptLabel.Text = '';
               app.EndTrial();
            end
         end
      end

      %--------------------------------------------------------------------
      function RewardTimerCallback(app, ~, ~)
         if startsWith(app.availableRewards{app.rewardIndex}, 'Toy')
            app.hOntrak.TurnOff();
         else
            app.hVLC.stop();
            %             app.hMixer.SetChannelProperty('participant', 'mute', false);
         end
         app.PromptLabel.Text = '';
         app.trialIsActive = false;
         app.rewardIsActive = false;
         app.EndTrial();
      end

      %--------------------------------------------------------------------
      function H = DefaultHardwareSettings(~)
         H.motuAddress = '169.254.221.196';
         H.outputDevice = 'Out 1-24 (MOTU Pro Audio)';
         H.micDevice = 'Microphone (Polsen US-OB-33)';
         H.waverMicDevice = '';
      end

      %--------------------------------------------------------------------
      function H = DefaultVoiceSettings(~)
         H.useVoice = false;
         H.trialStart = "Trial start";
         H.trainingPhase = "Training phase";
         H.testPhase = "Test phase";
         H.bounceBack = "Bounce back";
         H.hit = "Hit";
         H.miss = "Miss";
         H.falseAlarm = "False alarm";
         H.withhold = "Good, no signal";
      end

      %--------------------------------------------------------------------
      function S = DefaultSettings(app)
         S.stimulus.repsPerTrial = 3;
         S.stimulus.ITI_s = 1;
         S.stimulus.trialDuration_s = 4;
         S.stimulus.trainingValue = app.DefaultTrainingValue;
         S.stimulus.easyValue = app.DefaultTrainingValue;
         S.stimulus.testValues = app.DefaultTestValues;

         S.reward.useToys = true;
         S.reward.minToyTime_s = 3;
         S.reward.useVideo = true;
         S.reward.minVideoTime_s = 5;
         S.reward.videoPlaylist = {};

         S.phaseDefs = app.DefaultPhaseDefinition();
         S.phaseDefs.name = 'Training';
         S.phaseDefs.numSignal = 8;
         S.phaseDefs.numNoSignal = 2;
         S.phaseDefs.rewardRequiresResponse = false;

         S.phaseDefs(2) = app.DefaultPhaseDefinition();
         S.phaseDefs(2).name = 'Easy';
         S.phaseDefs(2).bounceBack.ifMaxNumTrials = false;
         S.phaseDefs(2).bounceBack.consecutiveMissType = 'none';
         S.phaseDefs(2).passBounceIf.criterion = 'trials';
         S.phaseDefs(2).passBounceIf.maxNumTrials = 12;

         S.phaseDefs(3) = app.DefaultPhaseDefinition();
         S.phaseDefs(3).name = 'Test';
      end

      %--------------------------------------------------------------------
      function P = DefaultPhaseDefinition(~)
         P.name = '';
         P.stimulusValue = NaN;
         P.numSignal = 10;
         P.numNoSignal = 10;
         P.rewardRequiresResponse = true;

         P.passIf.criterion = 'both';
         P.passIf.numSignalCorrect = 4;
         P.passIf.outOfNumSignal = 5;
         P.passIf.numNoSignalCorrect = 4;
         P.passIf.outOfNumNoSignal = 5;
         P.passIf.numTrialsCorrect = 5;
         P.passIf.outOfNumTrials = 6;
         P.passIf.maxNumTrials = 20;

         P.passBounceIf = P.passIf;

         P.bounceBack.ifMaxNumTrials = true;
         P.bounceBack.ifConsecutiveMiss = false;
         P.bounceBack.numConsecutiveMiss = 3;
         P.bounceBack.consecutiveMissWhat = 'signals';
      end

      %--------------------------------------------------------------------
      function HardwareSettingsChanged(app, settings)
         app.hardware = settings;
         setsharedpref(mfilename, 'HardwareSettings', app.hardware);
         app.AddLogEntry('Hardware settings changed');
      end

      %--------------------------------------------------------------------
      function VoiceSettingsChanged(app, settings)
         app.voice = settings;
         setsharedpref(mfilename, 'VoiceSettings', app.voice);
         app.AddLogEntry('Voice settings changed');
      end

      %--------------------------------------------------------------------
      function PhaseSettingsChanged(app, settings)
         idx = find(strcmp({app.settings.phaseDefs.name}, settings.name));
         app.settings.phaseDefs(idx) = settings;
         app.settingsAreDirty = true;

         if idx == app.currentPhase.Number
            app.currentPhase = app.currentPhase.UpdateSettings(app.settings.phaseDefs(idx));
         end

         app.AddLogEntry(sprintf('%s phase settings changed', settings.name));
      end

      %--------------------------------------------------------------------
      function PhaseTableCellSelection(app, ~, ~)
         rowNum = app.PhaseTable.Selection(1);

         if rowNum < 4 && app.PhaseTable.Selection(2) == 8
            OPPPhaseSettings(app.settings.phaseDefs(rowNum), app.UIFigure.Position, @app.PhaseSettingsChanged);
            app.PhaseTable.Selection = [app.currentPhase.Number 1];
         else
            app.SetPhase(rowNum, 'forward');
         end
      end

      %--------------------------------------------------------------------
      function HighlightPhaseTableRow(app, rowNum)
         highlightStyle = uistyle("BackgroundColor", "#F48B74", "FontColor", 'w', 'FontWeight', 'bold');
         plainStyle = uistyle("BackgroundColor", [1 1 1], "FontColor", 'k', 'FontWeight', 'normal');

         nrows = size(app.PhaseTable.Data, 1);
         for k = 1:nrows
            if k < inf
               addStyle(app.PhaseTable, plainStyle, "cell", [k k; 1 2]')
            else
               addStyle(app.PhaseTable, plainStyle, "row", k);
            end
         end

         if rowNum < inf
            addStyle(app.PhaseTable, highlightStyle, "cell", [rowNum rowNum; 1 2]');
         else
            addStyle(app.PhaseTable, highlightStyle, "row", rowNum);
         end
      end

      %--------------------------------------------------------------------
      function SettingsChanged(app, settings, stimChanged, videoChanged)
         reinitOntrak = app.settings.reward.useToys ~= settings.reward.useToys;
         reinitVLC = app.settings.reward.useVideo ~= settings.reward.useVideo;

         app.settings = settings;
         app.settingsAreDirty = true;
         app.AddLogEntry('Settings changed');

         if stimChanged
            stim = struct(...
               'trainingValue', app.settings.stimulus.trainingValue, ...
               'easyValue', app.settings.stimulus.easyValue, ...
               'testValues', app.settings.stimulus.testValues ...
               );
            setsharedpref(mfilename, [app.MeasurementType '_Stim'], stim);
            app.InitializePhases();
         end

         if reinitOntrak
            app.hardwareStatus.ontrak = app.InitializeOntrak();
         end

         if reinitVLC
            app.hardwareStatus.vlc = app.InitializeVLC();
         end

         if videoChanged
            app.SetVideoList();
         end

         app.StartButton.Enable = app.hardwareStatus.ontrak && app.hardwareStatus.vlc && app.hardwareStatus.audio && app.hardwareStatus.mixer;

         if stimChanged
            app.SetPhase(app.currentPhase.Number, 'forward');
         end
      end

      %--------------------------------------------------------------------
      function SaveSettings(app)
         filePath = fullfile(app.ResourceFolder, 'OppSettings.mat');

         settings = app.settings; %#ok<ADPROP>
         save(filePath, 'settings');
         app.settingsAreDirty = false;
      end

      %--------------------------------------------------------------------
      function SetFocus(app)
         % kluge to get focus to figure so the key presses will register
         % can use focus() in 2022
         robot = java.awt.Robot;
         figPos = app.UIFigure.Position;
         startPos = app.StartButton.Position;

         screenSize = get(0, 'ScreenSize');

         x = figPos(1) + startPos(1) + startPos(3) + 50;
         y = figPos(2) + startPos(2) + startPos(4);

         robot.mouseMove(x, screenSize(4) - y);
         robot.mousePress  (java.awt.event.InputEvent.BUTTON1_MASK);
         robot.mouseRelease(java.awt.event.InputEvent.BUTTON1_MASK);
      end

      %--------------------------------------------------------------------
      function SimulateRun(app)
         app.stopSimulation = false;
         while true
            result = app.SimulateTrial();
            if isequal(result, 'continue')
               if app.stopSimulation
                  app.EndRun('Simulation stopped');
                  app.SaveData();
                  break;
               else
                  pause(0.25);
               end
            else
               break;
            end
         end
      end

      %--------------------------------------------------------------------
      function result = SimulateTrial(app)
         [~, iActive] = intersect(lower(app.availableRewards), app.activeRewards);
         app.rewardIndex = iActive(randi(length(iActive)));
         app.responseTime = unifrnd(2,4);

         probHit = 1;
         probFA = 0;

         if app.currentPhase.Number == 1
         elseif app.currentPhase.Number == 2
         elseif app.currentPhase.Number ~= app.bouncedBackFrom
            nv = length(app.settings.stimulus.testValues);
            f = (app.currentPhase.Number - 2) / (nv - 1);
            delta = 0.1 + f*0.75;
            probHit = 1 - delta;
            probFA = 0.25;
         end

         if app.currentPhase.CurrentTrialHasSignal()
            app.haveResponse = unifrnd(0,1) < probHit;
            if app.haveResponse
               msg = 'HIT (signal)';
               color = [0 0.75 0];
               if app.voice.useVoice
                  app.Speak(app.voice.hit);
               end
            else
               msg = 'MISS (signal)';
               color = [0.75 0 0];
               if app.voice.useVoice
                  app.Speak(app.voice.miss);
               end
            end
         else
            app.haveResponse = unifrnd(0,1) < probFA;
            if app.haveResponse
               msg = 'FALSE ALARM (no signal)';
               color = [0.75 0 0];
               if app.voice.useVoice
                  app.Speak(app.voice.falseAlarm);
               end
            else
               msg = 'WITHHOLD (no signal)';
               color = [0 0.75 0];
               if app.voice.useVoice
                  app.Speak(app.voice.withhold);
               end
            end
         end
         app.TrialResultLabel.Text = msg;
         app.TrialResultLabel.FontColor = color;

         result = app.EndTrial();
      end

      %--------------------------------------------------------------------
      function ToggleTrainingCheck(app)
         app.TrainingLabel.Visible = 'on';
         app.TrainingLabel.Enable = 'off';
         drawnow;

         app.trainingCheck = ~app.trainingCheck;
         app.hStreamer.TrainTest(app.trainingCheck);

         latched = app.hStreamer.WaitForLatch(5);
         if ~latched
            app.AddLogEntry('Failed to toggle training mode.');
         end

         app.hMixer.EnableTesterStimulus(app.trainingCheck || app.currentPhase.Number == 1);

         if ~app.trainingCheck
            app.TrainingLabel.Visible = 'off';
         end
         app.TrainingLabel.Enable = 'on';
         drawnow;
      end

      %--------------------------------------------------------------------
      function valueChanged = CycleManualValue(app, key)
         valueChanged = false;
         value = app.ValueEditField.Value;
         [~, valIndex] = min(abs(value - app.manualValues));

         if isequal(key, 'downarrow')
            if valIndex == length(app.manualValues), return; end
            valIndex = valIndex + 1;
            app.ValueEditField.Value = app.manualValues(valIndex);
            valueChanged = true;
            return;
         end

         if valIndex == 1, return; end

         valIndex = valIndex - 1;
         app.ValueEditField.Value = app.manualValues(valIndex);
         valueChanged = true;
      end

      %--------------------------------------------------------------------
      function PlotManualData(app)


         xmin = min(app.manualValues);
         xmax = max(app.manualValues);

         if ~isempty(app.manualData) && ~isempty(app.manualData.Value)
            xmin = min(xmin, min(app.manualData.Value));
            xmax = min(xmax, max(app.manualData.Value));
         end

         set(app.manualAxes, 'XLim', [xmin xmax]);
         if app.manualValues(1) > xmin
            set(app.manualAxes, 'XDir', 'reverse');
         end

         set(app.manualAxes, ...
            'YLim', [-0.25 1.25], ...
            'YTick', [0 1], ...
            'YTickLabel', {'Miss', 'Hit'} ...
            );

         if ~isempty(app.manualData) && ~isempty(app.manualData.Value)
            h = plot(app.manualAxes, app.manualData.Value, app.manualData.Result, 'o');
            set(h, 'MarkerFaceColor', get(h, 'Color'));
         end
      end

      %--------------------------------------------------------------------
      function UpdateManualValue(app)
         Fs = 48000;
         Npts = round(Fs * app.settings.stimulus.ITI_s);
         zeroSignal = zeros(Npts, 1);

         app.hStreamer.SetConfig(app.hardware.outputDevice, Npts, app.hardware.micDevice, app.hardware.waverMicDevice);
         app.hStreamer.SetNumReps(app.settings.stimulus.repsPerTrial);

         value = app.ValueEditField.Value;
         [name, waveform] = app.SynthFcn(value, 'caregiver');

         yCaregiver = zeroSignal;
         yCaregiver(1:length(waveform)) = waveform;
         app.currentPhase = app.currentPhase.SetWaveformName('background2', name);

         [~, waveform] = app.SynthFcn(value, 'waver');
         yWaver = zeroSignal;
         yWaver(1:length(waveform)) = waveform;

         [name, waveform] = app.SynthFcn(value, 'background');
         yBackground = zeroSignal;
         yBackground(1:length(waveform)) = waveform;
         app.currentPhase = app.currentPhase.SetWaveformName('background', name);

         [name, waveform] = app.SynthFcn(value, 'signal');
         ySignal = zeroSignal;
         ySignal(1:length(waveform)) = waveform;
         app.currentPhase = app.currentPhase.SetWaveformName('signal', name);

         app.hStreamer.SetStimulusSet('Test', yCaregiver, yWaver, yBackground, ySignal);

         if ~app.hStreamer.IsStreaming
            app.hStreamer.Start();
            started = app.WaitForStreamState('running');
         else
            started = app.hStreamer.WaitForLatch(5);
         end

         if ~started
            app.CheckForStreamError();
            app.AddLogEntry('Failed to start audio stream.');
            return;
         end

         app.AddLogEntry('Audio stream updated (manual).');
      end
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
         % com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
         app.SetDebugMode();

         app.UIFigure.Name = ['OPP '  app.version];

         folder = fileparts(fileparts(which(mfilename)));
         addpath(fullfile(folder, 'MOTU'));
         addpath(fullfile(folder, 'Ontrak'));
         addpath(fullfile(folder, 'VLC'));
         
         % Restore figure position
         origin = getsharedpref('OPP', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end

         % Process input arguments
         for k = 1:2:length(varargin)
            app.(varargin{k}) = varargin{k+1};
         end
         app.DataFile = string(app.DataFile);

         app.StopButton.Visible = 'off';
         app.StartButton.Enable = false;
         app.TrialResultLabel.Text = '';
         app.PromptLabel.Text = "";

         % Initialize phase progress table
         app.phasePanelColor = app.PhaseProgressPanel.BackgroundColor;
         app.PhaseProgressTable.Data = cell(3, 2);
         app.PhaseProgressTable.RowName = {'Hit', 'False alarm', 'Trials'};
         cellStyle = uistyle("FontColor", [0 0.5 0], "HorizontalAlignment", 'center');
         addStyle(app.PhaseProgressTable, cellStyle, "row", 1);
         cellStyle = uistyle("FontColor", [0.75 0 0], "HorizontalAlignment", 'center');
         addStyle(app.PhaseProgressTable, cellStyle, "row", 2);
         cellStyle = uistyle("HorizontalAlignment", 'center');
         addStyle(app.PhaseProgressTable, cellStyle, "row", 3);

         % Initialize timers
         app.trialTimer = timer('TimerFcn', @app.TrialTimerCallback);
         app.rewardTimer = timer('TimerFcn', @app.RewardTimerCallback);

         app.InitializeDataFile();
         app.InitializeLog();

         app.RestoreSettings();
         app.InitializePhases();

         hardwareReady = app.InitializeHardware();
         if hardwareReady
            hardwareReady = app.SetLaterality(app.Ear);
            if ~hardwareReady
               error('Failed to set laterality on mixer');
            end
         end


         if hardwareReady || ~isequal(app.debug.simulation, 'off')
            app.StartButton.Enable = true;
            app.StopButton.Visible = 'on';
         end

         if ~isequal(app.debug.simulation, 'off')
            app.AddLogEntry('*** SIMULATED RUN ***');
         end

         app.SetVideoList();

         % try
         %    app.SetTrainers();
         % catch
         %    % Level error will get trapped setting the phase
         % end
         app.SetPhase(1, 'forward');

         app.SetFocus();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

         if app.settingsAreDirty
            app.SaveSettings();
         end

         uiresume(app.UIFigure);
         pos = app.UIFigure.Position;
         setsharedpref('OPP', 'Position', pos(1:2));

         try
            if ~isempty(app.hStreamer)
               app.AddLogEntry('stopping AudioStreamer...');
               app.hStreamer.Stop();
               app.WaitForStreamState('stopped');
               app.AddLogEntry('closing AudioStreamer...');
               app.hStreamer.Close();
            end
         catch ex
            app.AddLogEntry(['Error closing audio stream: ' ex.message]);
            app.hStreamer.Close();
         end

         try
            if ~isempty(app.hOntrak)
               app.AddLogEntry('closing Ontrak...');
               delete(app.hOntrak);
            end
         catch ex
            app.AddLogEntry(['Error closing Ontrak: ' ex.message]);
         end

         try
            if ~isempty(app.hVLC)
               app.AddLogEntry('closing VLC...');
               app.hVLC.quit();
            end
         catch ex
            app.AddLogEntry(['Error closing VLC: ' ex.message]);
         end

         try
            if ~isempty(app.hMixer)
               app.AddLogEntry('closing Mixer...');
               app.hMixer.Close();
            end
         catch ex
            app.AddLogEntry(['Error closing Mixer: ' ex.message]);
         end

         try
            if ~isempty(app.EndFcn)
               app.EndFcn();
            end
         catch ex
            app.AddLogEntry(['Error closing OPP: ' ex.message]);
         end

         try
            app.AddLogEntry('...OPP finished.');
            app.FlushLog();

            if length(app.phaseData) == 1 && isempty(app.phaseData.correct)
               delete(app.DataFileEditField.Value);
               % delete(strrep(app.DataFileEditField.Value, '.mat', '.log'));
            end
         catch ex
            disp(ex.message);
         end

         delete(app)
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
         OPPSettings(app.settings, app.UIFigure.Position, fullfile(app.RootFolder, 'Video Files'), @app.SettingsChanged);
        end

        % Menu selected function: ToysMenu_Test
        function ToysMenu_TestSelected(app, event)
         h = OntrakTest(app.hOntrak);
         uiwait(h.UIFigure);
        end

        % Menu selected function: AudioMenu_Test
        function AudioMenu_TestSelected(app, event)
         if false
            Fs = 50000; %#ok<UNRCH>
            F = 500;
            T = 1;
            duration = 0.25;
            ramp = 0.01;

            npts = round(Fs * T);
            tone = zeros(npts, 1);
            noise = tone;

            nptsDur = round(Fs * duration);

            nptsRamp = floor(Fs * ramp);
            hw = hanning(nptsRamp * 2);
            win = [hw(1:nptsRamp); ones(nptsDur-length(hw), 1); hw(nptsRamp+1:end)];


            t = (0:nptsDur-1) / nptsDur * T;
            y = sin(2*pi*F*t(:));
            tone(1:nptsDur) = y .* win;
            noise(1:nptsDur) = normrnd(0, 1, nptsDur, 1) .* win;

            app.hStreamer.SetNumReps(3);
            app.hStreamer.SetSignal('caregiver', '500-Hz tone', tone);
            app.hStreamer.SetSignal('waver', '500-Hz tone', tone);
            app.hStreamer.SetSignal('background', '500-Hz tone', tone);
            app.hStreamer.SetSignal('signal', 'noise burst', noise);
         else
            Fs = 48000;
         end

         app.hStreamer.SetConfig(app.hardware.outputDevice, app.hardware.micDevice, app.hardware.waverMicDevice, Fs, false);
         h = AudioStreamerTest(app.hStreamer, app.hMixer);
         uiwait(h.UIFigure);
        end

        % Menu selected function: AudioMenu_Settings
        function AudioMenu_SettingsSelected(app, event)
         OPPHardwareSettings(app.UIFigure.Position, app.hardware, app.hStreamer, app.hMixer, @app.HardwareSettingsChanged);
        end

        % Menu selected function: RestartMenu
        function AudioMenu_RestartSelected(app, event)
         if ~app.hardwareStatus.audio
            app.hStreamer.Close();
         end

         d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate', 'on');
         drawnow;
         d.Message = 'Initializing audio streamer...';
         app.hardwareStatus.audio = app.InitializeAudioStreamer();
         drawnow;
         delete(d);
        end

        % Menu selected function: VideoMenu_Test
        function VideoMenu_TestSelected(app, event)
         if ~isempty(app.hVLC)
            h = VLCTest(app.hVLC, app.settings.reward.videoPlaylist);
            uiwait(h.UIFigure);
         end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         app.SetFocus();

         app.StartButton.Visible = false;
         app.StopButton.Visible = true;
         app.PhaseTable.Enable = 'inactive';
         app.ManualCheckBox.Enable = 'off';

         app.availableRewards = {};
         app.rewardDurations = [];
         if app.settings.reward.useToys
            app.availableRewards = {'Toy1', 'Toy2'};
            app.rewardDurations = app.settings.reward.minToyTime_s * [1 1];
         end
         if app.settings.reward.useVideo
            app.availableRewards{end+1} = 'Video';
            app.rewardDurations(end+1) = app.settings.reward.minVideoTime_s;
         end

         if ~app.manualMode
            app.PhaseProgressPanel.Title = sprintf('Progress on Phase #%d', app.currentPhase.Number);
            app.PhaseProgressTable.Data = app.currentPhase.GetTableData();
         end

         if app.hardwareStatus.mixer
            app.hMixer.MuteAudioStream(false);
            app.hMixer.EnableTesterStimulus(app.currentPhase.Number == 1);
         end

         app.PromptLabel.Text = "press ENTER to start trial";
         app.PromptLabel.FontColor = 'k';

         if app.manualMode
            app.manualData = struct('Value', [], 'Result', []);
            app.AddLogEntry('Started manual tracking...');
         else
            app.AddLogEntry(sprintf('Started Phase #%d...', app.currentPhase.Number));
         end

         app.isRunning = true;

         if isequal(app.debug.simulation, 'run')
            app.SimulateRun();
         end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
         if isequal(app.debug.simulation, 'off')
            app.EndRun('Run stopped');
            app.SaveData();
         else
            app.stopSimulation = true;
         end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
         key = event.Key;
         if isequal(key, '1') || isequal(key, '2')
            % --- TURN ON TOY ---
            if ~app.isRunning && app.hardwareStatus.ontrak
               [toy1, toy2, ~] = app.hOntrak.GetState();
               if isequal(key, '1')
                  toy1 = ~toy1;
               end
               if isequal(key, '2')
                  toy2 = ~toy2;
               end
               app.hOntrak.TurnOn(toy1, toy2, true);
            end

         elseif isequal(key, '3')
            % --- TURN TOYS OFF ---
            app.hOntrak.TurnOff();

         elseif isequal(key, '4')
            % --- TOGGLE VIDEO PLAYBACK ---
            if ~app.isRunning && app.hardwareStatus.vlc
               status = app.hVLC.Status();
               if isequal(status, 'stopped')
                  app.hVLC.playSpecial();
               else
                  app.hVLC.stop();
               end
            end

         elseif isequal(key, 't')
            % --- TOGGLE TRAINING MODE ---
            if app.isRunning
               app.ToggleTrainingCheck();
            end

         elseif isequal(key, 'space')
            % --- TOGGLE TALKBACK ---
            if app.hardwareStatus.audio && app.hardwareStatus.mixer
               app.hMixer.ToggleTalkback();
            end

         elseif isequal(key, 'return') || isequal(key, 'enter')
            % --- INITIATE TRIAL ---
            if isequal(app.debug.simulation, 'off')
               if app.isRunning && ~app.trialIsActive && ~app.rewardIsActive
                  app.StartTrial();
               end
            elseif isequal(app.debug.simulation, 'trial') && app.isRunning
               app.SimulateTrial();
            end

         elseif (isequal(key, 'add') || isequal(key, '\')) && isequal(app.debug.simulation, 'off')
            % --- TRIAL RESPONSE ---
            if app.isRunning && app.trialIsActive && ~app.rewardIsActive && ~app.haveResponse
               app.haveResponse = true;
               app.responseTime = toc(app.trialStartTime);
               app.ProcessResponse();
            end

         elseif isequal(key, 'downarrow') || isequal(key, 'uparrow')
            % --- CYCLE MANUAL MODE STIMULUS VALUE ---
            valueChanged = app.CycleManualValue(key);
            if valueChanged && app.manualMode
               app.UpdateManualValue();
            end
         end

        end

        % Menu selected function: CalibrateMenu
        function CalibrateMenuSelected(app, event)
         fcorner = app.CornerFrequencies;
         if isempty(fcorner)
            fcorner = aptc_get_corner_freqs('');
         end

         app.hStreamer.SetConfig(app.hardware.outputDevice, app.hardware.micDevice, app.hardware.waverMicDevice, 48000, false);
         h = OPP_AcousticCalibration(fcorner, app.CalPath, app.hStreamer, app.hMixer);
         uiwait(h.UIFigure);

         if app.hardwareStatus.audio && app.hardwareStatus.mixer
            app.SetAudioStream(app.currentPhase.Number==1, app.currentPhase.StimValue);
         end

        end

        % Menu selected function: RestartMenu_2
        function MixerMenu_RestartSelected(app, event)
         if app.hardwareStatus.mixer
            app.hMixer.Close();
         end

         % execState = char(app.hMixer.GetExecutionState());
         % windowState = char(app.hMixer.GetWindowState());
         % app.AddLogEntry(sprintf('Showing mixer (current = %s, %s)', execState, windowState));

         d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate', 'on');
         drawnow;
         d.Message = 'Initializing mixer...';
         app.hardwareStatus.mixer = app.InitializeMixer(d);
         drawnow;
         delete(d);

         %             result = app.hMixer.Show();
         %
         %             if ~result
         %                app.AddLogEntry('Failed to show mixer');
         %             end
        end

        % Menu selected function: OpenMenu
        function HelpMenu_OpenSelected(app, event)
         %          url = fullfile(fileparts(fileparts(which(mfilename))), 'Help', 'index.htm');
         %          web(url);
         url = fullfile(fileparts(fileparts(which(mfilename))), 'Help', 'jga-opp-help.chm');
         system(['hh mk:@MSITStore:' url ' &']);
        end

        % Value changed function: UseToy1CheckBox, UseToy2CheckBox, 
        % ...and 1 other component
        function UseRewardCheckBoxValueChanged(app, event)
         if length(app.activeRewards) == 1 && ~event.Source.Value
            event.Source.Value = 1;
            return;
         end

         name = event.Source.Tag;
         if event.Source.Value
            app.activeRewards = unique([app.activeRewards {name}]);
         else
            app.activeRewards = app.activeRewards(~strcmp(app.activeRewards, name));
         end

         setsharedpref(mfilename, 'ActiveRewards', app.activeRewards);

        end

        % Menu selected function: VoiceMenu
        function VoiceMenuSelected(app, event)
         OPPVoiceSettings(app.UIFigure.Position, app.voice, app.hVoice, app.hStreamer, @app.VoiceSettingsChanged);
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
         app.Speak(app.voice.trainingPhase);
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
         app.Speak(app.voice.testPhase);
        end

        % Button pushed function: BounceButton
        function BounceButtonPushed(app, event)
         app.Speak(app.voice.bounceBack);
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
         value = app.ManualCheckBox.Value;
         app.manualMode = value;
         app.ManualPanel.Visible = value;
         app.PhaseTable.Visible = ~value;

         if app.manualMode
            app.UpdateManualValue();
         else
            app.SetAudioStream(app.currentPhase.Number==1, app.currentPhase.StimValue);
         end
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
         if app.manualMode
            app.UpdateManualValue();
         end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'OPP';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.EditMenu = uimenu(app.SettingsMenu);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ToysMenu = uimenu(app.ToolsMenu);
            app.ToysMenu.Text = 'Toys';
            app.ToysMenu_Test = uimenu(app.ToysMenu);
            app.ToysMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @ToysMenu_TestSelected, true);
            app.ToysMenu_Test.Text = 'Test';
            app.VideoMenu = uimenu(app.ToolsMenu);
            app.VideoMenu.Text = 'Video';
            app.VideoMenu_Test = uimenu(app.VideoMenu);
            app.VideoMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @VideoMenu_TestSelected, true);
            app.VideoMenu_Test.Text = 'Test';
            app.AudioMenu = uimenu(app.ToolsMenu);
            app.AudioMenu.Text = 'Audio';
            app.AudioMenu_Test = uimenu(app.AudioMenu);
            app.AudioMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_TestSelected, true);
            app.AudioMenu_Test.Text = 'Test';
            app.AudioMenu_Settings = uimenu(app.AudioMenu);
            app.AudioMenu_Settings.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_SettingsSelected, true);
            app.AudioMenu_Settings.Text = 'Settings';
            app.RestartMenu = uimenu(app.AudioMenu);
            app.RestartMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_RestartSelected, true);
            app.RestartMenu.Separator = 'on';
            app.RestartMenu.Text = 'Restart';
            app.CalibrateMenu = uimenu(app.AudioMenu);
            app.CalibrateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateMenuSelected, true);
            app.CalibrateMenu.Separator = 'on';
            app.CalibrateMenu.Text = 'Calibrate';
            app.MixerMenu = uimenu(app.ToolsMenu);
            app.MixerMenu.Text = 'Mixer';
            app.RestartMenu_2 = uimenu(app.MixerMenu);
            app.RestartMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MixerMenu_RestartSelected, true);
            app.RestartMenu_2.Text = 'Restart';
            app.VoiceMenu = uimenu(app.ToolsMenu);
            app.VoiceMenu.MenuSelectedFcn = createCallbackFcn(app, @VoiceMenuSelected, true);
            app.VoiceMenu.Text = 'Voice';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OpenMenu = uimenu(app.HelpMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_OpenSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ManualPanel = uipanel(app.UIFigure);
            app.manualAxes = uiaxes(app.ManualPanel);
            xlabel(app.manualAxes, 'Value')
            ylabel(app.manualAxes, 'Result')
            zlabel(app.manualAxes, 'Z')
            app.ValueEditFieldLabel = uilabel(app.ManualPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.ManualPanel, 'numeric');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.PhaseTable = uitable(app.UIFigure);
            app.PhaseTable.ColumnName = {'Phase'; 'Value'; 'Hit'; ''; 'FA'; ''; 'd'''; ''};
            app.PhaseTable.RowName = {};
            app.PhaseTable.Multiselect = 'off';
            app.ToolsPanel = uipanel(app.UIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.ToysLampLabel = uilabel(app.ToolsPanel);
            app.ToysLampLabel.Text = 'Toys';
            app.ToysLamp = uilamp(app.ToolsPanel);
            app.VideoLampLabel = uilabel(app.ToolsPanel);
            app.VideoLampLabel.Text = 'Video';
            app.VideoLamp = uilamp(app.ToolsPanel);
            app.AudioLampLabel = uilabel(app.ToolsPanel);
            app.AudioLampLabel.Text = 'Audio';
            app.AudioLamp = uilamp(app.ToolsPanel);
            app.MixerLampLabel = uilabel(app.ToolsPanel);
            app.MixerLampLabel.Text = 'Mixer';
            app.MixerLamp = uilamp(app.ToolsPanel);
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.LogTextArea.Editable = 'off';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'sign_stop.png';
            app.StopButton.Text = {''; ''};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'media_play_green.png';
            app.StartButton.Text = 'Start';
            app.DataFileEditField = uieditfield(app.UIFigure, 'text');
            app.DataFileEditField.Editable = 'off';
            app.PhaseProgressPanel = uipanel(app.UIFigure);
            app.PhaseProgressPanel.Title = 'Phase progress';
            app.PhaseProgressTable = uitable(app.PhaseProgressPanel);
            app.PhaseProgressTable.ColumnName = {'Total'; 'Last N'};
            app.PhaseProgressTable.RowName = {'Trials'; 'Hits'; 'False alarms'};
            app.PhaseProgressTable.SelectionType = 'row';
            app.PhaseProgressTable.Multiselect = 'off';
            app.TrialResultLabel = uilabel(app.PhaseProgressPanel);
            app.PromptLabel = uilabel(app.PhaseProgressPanel);
            app.PromptLabel.Text = 'Label2';
            app.VoicePanel = uipanel(app.PhaseProgressPanel);
            app.TrainingButton = uibutton(app.VoicePanel, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.TestButton = uibutton(app.VoicePanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.BounceButton = uibutton(app.VoicePanel, 'push');
            app.BounceButton.ButtonPushedFcn = createCallbackFcn(app, @BounceButtonPushed, true);
            app.BounceButton.Text = 'Bounce';
            app.VoicecuesLabel = uilabel(app.VoicePanel);
            app.VoicecuesLabel.Text = 'Voice cues';
            app.UseToy1CheckBox = uicheckbox(app.UIFigure);
            app.UseToy1CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseRewardCheckBoxValueChanged, true);
            app.UseToy1CheckBox.Tag = 'toy1';
            app.UseToy1CheckBox.Text = 'Use toy 1';
            app.UseVideoCheckBox = uicheckbox(app.UIFigure);
            app.UseVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseRewardCheckBoxValueChanged, true);
            app.UseVideoCheckBox.Tag = 'video';
            app.UseVideoCheckBox.Text = 'Use video';
            app.UseToy2CheckBox = uicheckbox(app.UIFigure);
            app.UseToy2CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseRewardCheckBoxValueChanged, true);
            app.UseToy2CheckBox.Tag = 'toy2';
            app.UseToy2CheckBox.Text = 'Use toy 2';
            app.TrainingLabel = uilabel(app.UIFigure);
            app.TrainingLabel.Visible = 'off';
            app.TrainingLabel.Text = 'TRAINING STIMULUS';
            app.ManualCheckBox = uicheckbox(app.UIFigure);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPP(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end