classdef OPPHardwareSettings < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                   matlab.ui.Figure
      CalibrationfilesPanel      matlab.ui.container.Panel
      ParticipantEditField       matlab.ui.control.EditField
      ParticipantEditFieldLabel  matlab.ui.control.Label
      PBbrowseButton             matlab.ui.control.Button
      WaverEditField             matlab.ui.control.EditField
      WaverEditFieldLabel        matlab.ui.control.Label
      WBrowseButton              matlab.ui.control.Button
      CaregiverEditField         matlab.ui.control.EditField
      CaregiverEditFieldLabel    matlab.ui.control.Label
      CBrowseButton              matlab.ui.control.Button
      MicDeviceDropDown          matlab.ui.control.DropDown
      MicrophoneLabel            matlab.ui.control.Label
      OutputDeviceDropDown       matlab.ui.control.DropDown
      OutputdeviceDropDownLabel  matlab.ui.control.Label
      MOTUStatusEditField        matlab.ui.control.EditField
      MOTUaddressEditField       matlab.ui.control.EditField
      MOTUaddressEditFieldLabel  matlab.ui.control.Label
      CancelButton               matlab.ui.control.Button
      OKButton                   matlab.ui.control.Button
      banner                     matlab.ui.control.Image
   end

   
   properties (Access = private)
      Settings % Description
      ChangeFcn;
      result = 'cancel';
      hstreamer
      hmixer
      calFolder;
   end
   
   methods (Access = private)
      
      function TryMOTU(app)
         app.MOTUStatusEditField.Value = 'trying...';
         drawnow;

         isAvailable = app.hmixer.IsAvailable(app.MOTUaddressEditField.Value);
         if isAvailable
            app.MOTUStatusEditField.Value = 'connected';
         else
            app.MOTUStatusEditField.Value = 'device not found';
         end
      end

      %--------------------------------------------------------------------
      function BrowseForCalFile(app, name)
         cf = app.([name 'EditField']).Value;
         if isempty(cf)
            cf = app.calFolder;
         end
         [f, p] = uigetfile('*.calib', 'Select a calibration file', cf);
         if ischar(f)
            app.([name 'EditField']).Value = fullfile(p, f);
            app.Settings.([lower(name) 'CalFile']) = fullfile(p,f);
         end
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, centerOn, settings, streamer, mixer, calFolder, changeFcn)
         app.Settings = settings;
         app.hstreamer = streamer;
         app.hmixer = mixer;
         app.calFolder = calFolder;
         app.ChangeFcn = changeFcn;

         pos = app.UIFigure.Position;
         pos(1) = centerOn(1) + centerOn(3)/2 - pos(3)/2;
         pos(2) = centerOn(2) + centerOn(4)/2 - pos(4)/2;
         app.UIFigure.Position = pos;

         app.MOTUaddressEditField.Value = app.Settings.motuAddress;

         value = app.hstreamer.EnumerateOutputDevices();
         dev = cell(value.Length, 1);
         for k = 1:value.Length
            dev{k} = char(value(k));
         end
         app.OutputDeviceDropDown.Items = dev;
         if contains(app.Settings.outputDevice, dev)
            app.OutputDeviceDropDown.Value = app.Settings.outputDevice;
         end

         value = app.hstreamer.EnumerateMicrophones();
         dev = cell(value.Length, 1);
         for k = 1:value.Length
            dev{k} = char(value(k));
         end
         app.MicDeviceDropDown.Items = dev;
         if contains(app.Settings.micDevice, dev)
            app.MicDeviceDropDown.Value = app.Settings.micDevice;
         end

         app.CaregiverEditField.Value = app.Settings.caregiverCalFile;
         app.WaverEditField.Value = app.Settings.waverCalFile;
         app.ParticipantEditField.Value = app.Settings.participantCalFile;

         drawnow;
         app.TryMOTU();

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if isequal(app.result, 'ok') && ~isempty(app.ChangeFcn)
            app.ChangeFcn(app.Settings);
         end
         uiresume(app.UIFigure);
         delete(app)
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         app.result = 'ok';
         close(app.UIFigure);         
      end

      % Button pushed function: CancelButton
      function CancelButtonPushed(app, event)
         app.result = 'cancel';
         close(app.UIFigure);
      end

      % Value changed function: MOTUaddressEditField
      function MOTUaddressEditFieldValueChanged(app, event)
         app.Settings.motuAddress = app.MOTUaddressEditField.Value;
         app.TryMOTU();         
      end

      % Button pushed function: CBrowseButton
      function CBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Caregiver');
      end

      % Button pushed function: WBrowseButton
      function WBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Waver');         
      end

      % Button pushed function: PBbrowseButton
      function PBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Participant');                  
      end

      % Value changed function: OutputDeviceDropDown
      function OutputDeviceDropDownValueChanged(app, event)
         app.Settings.outputDevice = app.OutputDeviceDropDown.Value;
      end

      % Value changed function: MicDeviceDropDown
      function MicDeviceDropDownValueChanged(app, event)
         app.Settings.micDevice = app.MicDeviceDropDown.Value;         
      end

      % Value changed function: CaregiverEditField
      function CaregiverEditFieldValueChanged(app, event)
         app.Settings.caregiverCalFile = app.CaregiverEditField.Value;
      end

      % Value changed function: WaverEditField
      function WaverEditFieldValueChanged(app, event)
         app.Settings.waverCalFile = app.WaverEditField.Value;
      end

      % Value changed function: ParticipantEditField
      function ParticipantEditFieldValueChanged(app, event)
         app.Settings.participantCalFile = app.ParticipantEditField.Value;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Hardware settings';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.CancelButton = uibutton(app.UIFigure, 'push');
         app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
         app.CancelButton.Text = 'Cancel';
         app.MOTUaddressEditFieldLabel = uilabel(app.UIFigure);
         app.MOTUaddressEditFieldLabel.Text = 'MOTU address';
         app.MOTUaddressEditField = uieditfield(app.UIFigure, 'text');
         app.MOTUaddressEditField.ValueChangedFcn = createCallbackFcn(app, @MOTUaddressEditFieldValueChanged, true);
         app.MOTUStatusEditField = uieditfield(app.UIFigure, 'text');
         app.MOTUStatusEditField.Editable = 'off';
         app.OutputdeviceDropDownLabel = uilabel(app.UIFigure);
         app.OutputdeviceDropDownLabel.Text = 'Output device';
         app.OutputDeviceDropDown = uidropdown(app.UIFigure);
         app.OutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDeviceDropDownValueChanged, true);
         app.MicrophoneLabel = uilabel(app.UIFigure);
         app.MicrophoneLabel.Text = 'Microphone';
         app.MicDeviceDropDown = uidropdown(app.UIFigure);
         app.MicDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @MicDeviceDropDownValueChanged, true);
         app.CalibrationfilesPanel = uipanel(app.UIFigure);
         app.CalibrationfilesPanel.Title = 'Calibration files';
         app.CBrowseButton = uibutton(app.CalibrationfilesPanel, 'push');
         app.CBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @CBrowseButtonPushed, true);
         app.CBrowseButton.Text = 'browse';
         app.CaregiverEditFieldLabel = uilabel(app.CalibrationfilesPanel);
         app.CaregiverEditFieldLabel.Text = 'Caregiver';
         app.CaregiverEditField = uieditfield(app.CalibrationfilesPanel, 'text');
         app.CaregiverEditField.ValueChangedFcn = createCallbackFcn(app, @CaregiverEditFieldValueChanged, true);
         app.WBrowseButton = uibutton(app.CalibrationfilesPanel, 'push');
         app.WBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @WBrowseButtonPushed, true);
         app.WBrowseButton.Text = 'browse';
         app.WaverEditFieldLabel = uilabel(app.CalibrationfilesPanel);
         app.WaverEditFieldLabel.Text = 'Waver';
         app.WaverEditField = uieditfield(app.CalibrationfilesPanel, 'text');
         app.WaverEditField.ValueChangedFcn = createCallbackFcn(app, @WaverEditFieldValueChanged, true);
         app.PBbrowseButton = uibutton(app.CalibrationfilesPanel, 'push');
         app.PBbrowseButton.ButtonPushedFcn = createCallbackFcn(app, @PBrowseButtonPushed, true);
         app.PBbrowseButton.Text = 'browse';
         app.ParticipantEditFieldLabel = uilabel(app.CalibrationfilesPanel);
         app.ParticipantEditFieldLabel.Text = 'Participant';
         app.ParticipantEditField = uieditfield(app.CalibrationfilesPanel, 'text');
         app.ParticipantEditField.ValueChangedFcn = createCallbackFcn(app, @ParticipantEditFieldValueChanged, true);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPPHardwareSettings(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end