classdef OPPPhaseSettings < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      ButtonGroup                    matlab.ui.container.ButtonGroup
      PassBounceButton               matlab.ui.control.ToggleButton
      PassButton                     matlab.ui.control.ToggleButton
      BouncebackorstopifPanel        matlab.ui.container.Panel
      consecutiveLabel               matlab.ui.control.Label
      MissCheckBox                   matlab.ui.control.CheckBox
      ORLabel                        matlab.ui.control.Label
      MissWhatDropDown               matlab.ui.control.DropDown
      NumMissEditField               matlab.ui.control.NumericEditField
      ReachMaxTrialsCheckBox         matlab.ui.control.CheckBox
      PassPanel                      matlab.ui.container.Panel
      trialsanykindcorrectLabel      matlab.ui.control.Label
      NumLastTrialsEditField         matlab.ui.control.NumericEditField
      ofthelastLabel_3               matlab.ui.control.Label
      NumTrialsEditField             matlab.ui.control.NumericEditField
      ANDLabel                       matlab.ui.control.Label
      signaltrialscorrectLabel       matlab.ui.control.Label
      NumLastSigEditField            matlab.ui.control.NumericEditField
      ofthelastLabel_2               matlab.ui.control.Label
      NumSigEditField                matlab.ui.control.NumericEditField
      nosignaltrialscorrectLabel     matlab.ui.control.Label
      NumLastNoEditField             matlab.ui.control.NumericEditField
      ofthelastLabel                 matlab.ui.control.Label
      MaxTrialsEditField             matlab.ui.control.NumericEditField
      MaxnumberoftrialsLabel         matlab.ui.control.Label
      NumNoEditField                 matlab.ui.control.NumericEditField
      CriterionGroup                 matlab.ui.container.ButtonGroup
      TrialsRadio                    matlab.ui.control.RadioButton
      BothRadio                      matlab.ui.control.RadioButton
      PhaseEditField                 matlab.ui.control.EditField
      PhaseEditFieldLabel            matlab.ui.control.Label
      RewardCheckBox                 matlab.ui.control.CheckBox
      BlockSizeEditField             matlab.ui.control.NumericEditField
      BlocksizeEditFieldLabel        matlab.ui.control.Label
      NumSigPerBlockEditField        matlab.ui.control.NumericEditField
      SignalsperblockEditFieldLabel  matlab.ui.control.Label
      CancelButton                   matlab.ui.control.Button
      OKButton                       matlab.ui.control.Button
      banner                         matlab.ui.control.Image
   end

   
   properties (Access = private)
      settings
      changeFcn
      result = 'cancel';
   end
   
   methods (Access = private)
      
      %--------------------------------------------------------------------
      function ShowPassCondition(app, direction)
         if isequal(direction, 'forward')
            c = app.settings.passIf;
         else
            c = app.settings.passBounceIf;
         end

         isBoth = isequal(c.criterion, 'both');
         if isBoth
            app.CriterionGroup.SelectedObject = app.BothRadio;
         else
            app.CriterionGroup.SelectedObject = app.TrialsRadio;
         end

         app.NumNoEditField.Enable = isBoth;
         app.NumLastNoEditField.Enable = isBoth;
         app.NumSigEditField.Enable = isBoth;
         app.NumLastSigEditField.Enable = isBoth;

         app.NumTrialsEditField.Enable = ~isBoth;
         app.NumLastTrialsEditField.Enable = ~isBoth;

         app.NumNoEditField.Value = c.numNoSignalCorrect;
         app.NumLastNoEditField.Value = c.outOfNumNoSignal;
         app.NumSigEditField.Value = c.numSignalCorrect;
         app.NumLastSigEditField.Value = c.outOfNumSignal;
         
         app.NumTrialsEditField.Value = c.numTrialsCorrect;
         app.NumLastTrialsEditField.Value = c.outOfNumTrials;

         app.MaxTrialsEditField.Value = c.maxNumTrials;
      end

      %--------------------------------------------------------------------
      
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, settings, positionOn, changeFcn)
         app.settings = settings;
         app.changeFcn = changeFcn;

         pos = app.UIFigure.Position;
         pos(1) = positionOn(1) + positionOn(3) + 10;
         pos(2) = positionOn(2);
         app.UIFigure.Position = pos;

         app.MissWhatDropDown.ItemsData = {'signals', 'trials'};

         app.PhaseEditField.Value = app.settings.name;
         app.BlockSizeEditField.Value = app.settings.numSignal + app.settings.numNoSignal;
         app.NumSigPerBlockEditField.Value = app.settings.numSignal;
         app.RewardCheckBox.Value = app.settings.rewardRequiresResponse;

         app.ReachMaxTrialsCheckBox.Value = app.settings.bounceBack.ifMaxNumTrials;
         app.MissCheckBox.Value = app.settings.bounceBack.ifConsecutiveMiss;
         app.NumMissEditField.Value = app.settings.bounceBack.numConsecutiveMiss;
         app.MissWhatDropDown.Value = app.settings.bounceBack.consecutiveMissWhat;

         if ~isequal(app.settings.name, 'Easy')
            app.PassBounceButton.Visible = false;
         end
         app.ButtonGroup.SelectedObject = app.PassButton;
         app.ShowPassCondition('forward');         
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if ~isempty(app.changeFcn) && isequal(app.result, 'ok')
            app.changeFcn(app.settings);
         end
         
         delete(app)         
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         app.result = 'ok';
         close(app.UIFigure);
      end

      % Button pushed function: CancelButton
      function CancelButtonPushed(app, event)
         app.result = 'cancel';
         close(app.UIFigure);        
      end

      % Value changed function: BlockSizeEditField
      function BlockSizeEditFieldValueChanged(app, event)
         value = app.BlockSizeEditField.Value;
         if value < app.settings.numSignal
            app.settings.numNoSignal = 0;
            app.settings.numSignal = value;
            app.NumSigPerBlockEditField.Value = value;
         else
            app.settings.numNoSignal = value - app.settings.numSignal;
         end         
      end

      % Value changed function: NumSigPerBlockEditField
      function NumSigPerBlockEditFieldValueChanged(app, event)
         app.settings.numSignal = app.NumSigPerBlockEditField.Value;
         if app.settings.numSignal < app.BlockSizeEditField.Value
            app.settings.numNoSignal = app.BlockSizeEditField.Value - app.settings.numSignal;
         else
            app.settings.numNoSignal = 0;
            app.BlockSizeEditField.Value = app.settings.numSignal;        
         end
      end

      % Value changed function: RewardCheckBox
      function RewardCheckBoxValueChanged(app, event)
         app.settings.rewardRequiresResponse = app.RewardCheckBox.Value;         
      end

      % Selection changed function: ButtonGroup
      function ButtonGroupSelectionChanged(app, event)
         selectedButton = app.ButtonGroup.SelectedObject;
         if contains(selectedButton.Text, 'bounce')
            direction = 'bounce';
         else
            direction = 'forward';
         end
         app.ShowPassCondition(direction);
      end

      % Selection changed function: CriterionGroup
      function CriterionGroupSelectionChanged(app, event)
         selectedButton = app.CriterionGroup.SelectedObject;

         isBoth = isequal(selectedButton, app.BothRadio);
         if isBoth
            crit = 'both';
         else
            crit = 'trials';
         end

         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.criterion = crit;
         else
            app.settings.passBounceIf.criterion = crit;
         end

         app.NumNoEditField.Enable = isBoth;
         app.NumLastNoEditField.Enable = isBoth;
         app.NumSigEditField.Enable = isBoth;
         app.NumLastSigEditField.Enable = isBoth;

         app.NumTrialsEditField.Enable = ~isBoth;
         app.NumLastTrialsEditField.Enable = ~isBoth;
         
      end

      % Value changed function: NumNoEditField
      function NumNoEditFieldValueChanged(app, event)
         value = app.NumNoEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.numNoSignalCorrect = value;
         else
            app.settings.passBounceIf.numNoSignalCorrect = value;
         end         
      end

      % Value changed function: NumLastNoEditField
      function NumLastNoEditFieldValueChanged(app, event)
         value = app.NumLastNoEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.outOfNumNoSignal = value;
         else
            app.settings.passBounceIf.outOfNumNoSignal = value;
         end                  
      end

      % Value changed function: NumSigEditField
      function NumSigEditFieldValueChanged(app, event)
         value = app.NumSigEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.numSignalCorrect = value;
         else
            app.settings.passBounceIf.numSignalCorrect = value;
         end                           
      end

      % Value changed function: NumLastSigEditField
      function NumLastSigEditFieldValueChanged(app, event)
         value = app.NumLastSigEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.outOfNumSignal = value;
         else
            app.settings.passBounceIf.outOfNumSignal = value;
         end                                    
      end

      % Value changed function: NumTrialsEditField
      function NumTrialsEditFieldValueChanged(app, event)
         value = app.NumTrialsEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.numTrialsCorrect = value;
         else
            app.settings.passBounceIf.numTrialsCorrect = value;
         end                                             
      end

      % Value changed function: NumLastTrialsEditField
      function NumLastTrialsEditFieldValueChanged(app, event)
         value = app.NumLastTrialsEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.outOfNumTrials = value;
         else
            app.settings.passBounceIf.outOfNumTrials = value;
         end                                                      
      end

      % Value changed function: MaxTrialsEditField
      function MaxTrialsEditFieldValueChanged(app, event)
         value = app.MaxTrialsEditField.Value;
         if isequal(app.ButtonGroup.SelectedObject, app.PassButton)
            app.settings.passIf.maxNumTrials = value;
         else
            app.settings.passBounceIf.maxNumTrials = value;
         end                                             
      end

      % Value changed function: ReachMaxTrialsCheckBox
      function ReachMaxTrialsCheckBoxValueChanged(app, event)
         app.settings.bounceBack.ifMaxNumTrials = app.ReachMaxTrialsCheckBox.Value;
         
      end

      % Value changed function: MissCheckBox
      function MissCheckBoxValueChanged(app, event)
         app.settings.bounceBack.ifConsecutiveMiss = app.MissCheckBox.Value;
      end

      % Value changed function: NumMissEditField
      function NumMissEditFieldValueChanged(app, event)
         app.settings.bounceBack.numConsecutiveMiss = app.NumMissEditField.Value;         
      end

      % Value changed function: MissWhatDropDown
      function MissWhatDropDownValueChanged(app, event)
         app.settings.bounceBack.consecutiveMissWhat = app.MissWhatDropDown.Value;        
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Phase settings';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.CancelButton = uibutton(app.UIFigure, 'push');
         app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
         app.CancelButton.Text = 'Cancel';
         app.SignalsperblockEditFieldLabel = uilabel(app.UIFigure);
         app.SignalsperblockEditFieldLabel.Text = 'Signals per block';
         app.NumSigPerBlockEditField = uieditfield(app.UIFigure, 'numeric');
         app.NumSigPerBlockEditField.Limits = [0 Inf];
         app.NumSigPerBlockEditField.ValueChangedFcn = createCallbackFcn(app, @NumSigPerBlockEditFieldValueChanged, true);
         app.BlocksizeEditFieldLabel = uilabel(app.UIFigure);
         app.BlocksizeEditFieldLabel.Text = 'Block size';
         app.BlockSizeEditField = uieditfield(app.UIFigure, 'numeric');
         app.BlockSizeEditField.Limits = [0 Inf];
         app.BlockSizeEditField.RoundFractionalValues = 'on';
         app.BlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeEditFieldValueChanged, true);
         app.RewardCheckBox = uicheckbox(app.UIFigure);
         app.RewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @RewardCheckBoxValueChanged, true);
         app.RewardCheckBox.Text = 'Reward requires response';
         app.PhaseEditFieldLabel = uilabel(app.UIFigure);
         app.PhaseEditFieldLabel.Text = 'Phase';
         app.PhaseEditField = uieditfield(app.UIFigure, 'text');
         app.PhaseEditField.Editable = 'off';
         app.PassPanel = uipanel(app.UIFigure);
         app.CriterionGroup = uibuttongroup(app.PassPanel);
         app.CriterionGroup.SelectionChangedFcn = createCallbackFcn(app, @CriterionGroupSelectionChanged, true);
         app.BothRadio = uiradiobutton(app.CriterionGroup);
         app.BothRadio.Text = '';
         app.BothRadio.Value = true;
         app.TrialsRadio = uiradiobutton(app.CriterionGroup);
         app.TrialsRadio.Text = '';
         app.NumNoEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumNoEditField.Limits = [0 Inf];
         app.NumNoEditField.RoundFractionalValues = 'on';
         app.NumNoEditField.ValueChangedFcn = createCallbackFcn(app, @NumNoEditFieldValueChanged, true);
         app.MaxnumberoftrialsLabel = uilabel(app.PassPanel);
         app.MaxnumberoftrialsLabel.Text = 'Max number of trials';
         app.MaxTrialsEditField = uieditfield(app.PassPanel, 'numeric');
         app.MaxTrialsEditField.Limits = [0 Inf];
         app.MaxTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrialsEditFieldValueChanged, true);
         app.ofthelastLabel = uilabel(app.PassPanel);
         app.ofthelastLabel.Text = 'of the last';
         app.NumLastNoEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumLastNoEditField.Limits = [0 Inf];
         app.NumLastNoEditField.RoundFractionalValues = 'on';
         app.NumLastNoEditField.ValueChangedFcn = createCallbackFcn(app, @NumLastNoEditFieldValueChanged, true);
         app.nosignaltrialscorrectLabel = uilabel(app.PassPanel);
         app.nosignaltrialscorrectLabel.Text = 'no-signal trials correct';
         app.NumSigEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumSigEditField.Limits = [0 Inf];
         app.NumSigEditField.RoundFractionalValues = 'on';
         app.NumSigEditField.ValueChangedFcn = createCallbackFcn(app, @NumSigEditFieldValueChanged, true);
         app.ofthelastLabel_2 = uilabel(app.PassPanel);
         app.ofthelastLabel_2.Text = 'of the last';
         app.NumLastSigEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumLastSigEditField.Limits = [0 Inf];
         app.NumLastSigEditField.RoundFractionalValues = 'on';
         app.NumLastSigEditField.ValueChangedFcn = createCallbackFcn(app, @NumLastSigEditFieldValueChanged, true);
         app.signaltrialscorrectLabel = uilabel(app.PassPanel);
         app.signaltrialscorrectLabel.Text = 'signal trials correct';
         app.ANDLabel = uilabel(app.PassPanel);
         app.ANDLabel.Text = '--- AND ---';
         app.NumTrialsEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumTrialsEditField.Limits = [0 Inf];
         app.NumTrialsEditField.RoundFractionalValues = 'on';
         app.NumTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NumTrialsEditFieldValueChanged, true);
         app.ofthelastLabel_3 = uilabel(app.PassPanel);
         app.ofthelastLabel_3.Text = 'of the last';
         app.NumLastTrialsEditField = uieditfield(app.PassPanel, 'numeric');
         app.NumLastTrialsEditField.Limits = [0 Inf];
         app.NumLastTrialsEditField.RoundFractionalValues = 'on';
         app.NumLastTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NumLastTrialsEditFieldValueChanged, true);
         app.trialsanykindcorrectLabel = uilabel(app.PassPanel);
         app.trialsanykindcorrectLabel.Text = 'trials (any kind) correct';
         app.BouncebackorstopifPanel = uipanel(app.UIFigure);
         app.BouncebackorstopifPanel.Title = 'Bounce back (or stop) if...';
         app.ReachMaxTrialsCheckBox = uicheckbox(app.BouncebackorstopifPanel);
         app.ReachMaxTrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReachMaxTrialsCheckBoxValueChanged, true);
         app.ReachMaxTrialsCheckBox.Text = 'Reach max number of trials';
         app.NumMissEditField = uieditfield(app.BouncebackorstopifPanel, 'numeric');
         app.NumMissEditField.Limits = [0 Inf];
         app.NumMissEditField.ValueChangedFcn = createCallbackFcn(app, @NumMissEditFieldValueChanged, true);
         app.MissWhatDropDown = uidropdown(app.BouncebackorstopifPanel);
         app.MissWhatDropDown.Items = {'signal trials', 'trials'};
         app.MissWhatDropDown.ValueChangedFcn = createCallbackFcn(app, @MissWhatDropDownValueChanged, true);
         app.MissWhatDropDown.Value = 'signal trials';
         app.ORLabel = uilabel(app.BouncebackorstopifPanel);
         app.ORLabel.Text = '--- OR ---';
         app.MissCheckBox = uicheckbox(app.BouncebackorstopifPanel);
         app.MissCheckBox.ValueChangedFcn = createCallbackFcn(app, @MissCheckBoxValueChanged, true);
         app.MissCheckBox.Text = 'Miss';
         app.consecutiveLabel = uilabel(app.BouncebackorstopifPanel);
         app.consecutiveLabel.Text = 'consecutive';
         app.ButtonGroup = uibuttongroup(app.UIFigure);
         app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
         app.PassButton = uitogglebutton(app.ButtonGroup);
         app.PassButton.Text = 'Pass condition';
         app.PassButton.Value = true;
         app.PassBounceButton = uitogglebutton(app.ButtonGroup);
         app.PassBounceButton.Text = 'Pass condition (bounce back)';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPPPhaseSettings(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end