classdef OPPSettings < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      RewardsPanel                   matlab.ui.container.Panel
      DownButton                     matlab.ui.control.Button
      UpButton                       matlab.ui.control.Button
      DeleteButton                   matlab.ui.control.Button
      MinToyTimeEditField            matlab.ui.control.NumericEditField
      MintoytimesEditFieldLabel      matlab.ui.control.Label
      VideoListBox                   matlab.ui.control.ListBox
      VideoplaylistListBoxLabel      matlab.ui.control.Label
      AddButton                      matlab.ui.control.Button
      UsevideoCheckBox               matlab.ui.control.CheckBox
      MinVideoTimeEditField          matlab.ui.control.NumericEditField
      MinvideotimesEditFieldLabel    matlab.ui.control.Label
      UsetoysCheckBox                matlab.ui.control.CheckBox
      PhasevaluesPanel               matlab.ui.container.Panel
      TestEditField                  matlab.ui.control.EditField
      TestEditFieldLabel             matlab.ui.control.Label
      EasyEditField                  matlab.ui.control.NumericEditField
      EasyEditFieldLabel             matlab.ui.control.Label
      TrainingEditField              matlab.ui.control.NumericEditField
      TrainingEditFieldLabel         matlab.ui.control.Label
      StimulusPanel                  matlab.ui.container.Panel
      TrialDurEditField              matlab.ui.control.NumericEditField
      TrialdurationsLabel            matlab.ui.control.Label
      IntervalEditField              matlab.ui.control.NumericEditField
      IntervalsEditFieldLabel        matlab.ui.control.Label
      RepeatsEditField               matlab.ui.control.NumericEditField
      RepeatspertrialEditFieldLabel  matlab.ui.control.Label
      CancelButton                   matlab.ui.control.Button
      OKButton                       matlab.ui.control.Button
      banner                         matlab.ui.control.Image
   end

   
   properties (Access = private)
      settings
      changeFcn
      videoFolder
      result = 'cancel';
      isDirty = false;
      expressionOK = true;
      stimChanged = false;
      videoChanged = false;
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, settings, centerOn, videoFolder, changeFcn)
         app.settings = settings;
         app.videoFolder = videoFolder;
         app.changeFcn = changeFcn;

         pos = app.UIFigure.Position;
         pos(1) = centerOn(1) + centerOn(3)/2 - pos(3)/2;
         pos(2) = centerOn(2) + centerOn(4)/2 - pos(4)/2;
         app.UIFigure.Position = pos;

         app.RepeatsEditField.Value = app.settings.stimulus.repsPerTrial;
         app.IntervalEditField.Value = app.settings.stimulus.ITI_s;
         app.TrialDurEditField.Value = app.settings.stimulus.trialDuration_s;
         
         app.TrainingEditField.Value = app.settings.stimulus.trainingValue;
         app.EasyEditField.Value = app.settings.stimulus.easyValue;
         app.TestEditField.Value = ['[ ' num2str(app.settings.stimulus.testValues(:)') ' ]'];

         app.UsetoysCheckBox.Value = app.settings.reward.useToys;
         app.MinToyTimeEditField.Value = app.settings.reward.minToyTime_s;

         app.UsevideoCheckBox.Value = app.settings.reward.useVideo;
         app.MinVideoTimeEditField.Value = app.settings.reward.minVideoTime_s;

         app.VideoListBox.Items = app.settings.reward.videoPlaylist;   
         app.VideoListBox.ItemsData = 1:length(app.settings.reward.videoPlaylist);
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if ~app.expressionOK, return; end
         
         if isequal(app.result, 'ok') && (app.isDirty || app.stimChanged)
            app.changeFcn(app.settings, app.stimChanged, app.videoChanged);
         end
         
         delete(app)         
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         app.result = 'ok';
         close(app.UIFigure);
      end

      % Button pushed function: CancelButton
      function CancelButtonPushed(app, event)
         app.result = 'cancel';
         close(app.UIFigure);
      end

      % Value changed function: TrainingEditField
      function TrainingEditFieldValueChanged(app, event)
         app.settings.stimulus.trainingValue = app.TrainingEditField.Value;
         app.stimChanged = true;
      end

      % Value changed function: EasyEditField
      function EasyEditFieldValueChanged(app, event)
         app.settings.stimulus.easyValue = app.EasyEditField.Value;
         app.stimChanged = true;
      end

      % Value changed function: TestEditField
      function TestEditFieldValueChanged(app, event)
         try
            app.settings.stimulus.testValues = eval(app.TestEditField.Value);
            app.TestEditFieldLabel.BackgroundColor = 'w';
            app.expressionOK = true;
            app.stimChanged = true;
         catch ex
            msgbox(['Expression error: ' ex.message], 'Settings', 'error');
            app.TestEditFieldLabel.BackgroundColor = 'r';
            app.expressionOK = false;
         end         
      end

      % Value changed function: RepeatsEditField
      function RepeatsEditFieldValueChanged(app, event)
         app.settings.stimulus.repsPerTrial = app.RepeatsEditField.Value;
         app.isDirty = true;                         
      end

      % Value changed function: IntervalEditField
      function IntervalEditFieldValueChanged(app, event)
         value = app.IntervalEditField.Value;
         value = round(value / 0.1) * 0.1;
         app.IntervalEditField.Value = value;
         app.settings.stimulus.ITI_s = value;
         app.isDirty = true;                         
      end

      % Value changed function: TrialDurEditField
      function TrialDurEditFieldValueChanged(app, event)
         app.settings.stimulus.trialDuration_s = app.TrialDurEditField.Value;
         app.isDirty = true;                                           
      end

      % Value changed function: UsetoysCheckBox
      function UsetoysCheckBoxValueChanged(app, event)
         app.settings.reward.useToys = app.UsetoysCheckBox.Value;
         app.isDirty = true;                                  
      end

      % Value changed function: MinToyTimeEditField
      function MinToyTimeEditFieldValueChanged(app, event)
         app.settings.reward.minToyTime_s = app.MinToyTimeEditField.Value;
         app.isDirty = true;                                  
      end

      % Value changed function: UsevideoCheckBox
      function UsevideoCheckBoxValueChanged(app, event)
         app.settings.reward.useVideo = app.UsevideoCheckBox.Value;
         app.isDirty = true;                                  
      end

      % Value changed function: MinVideoTimeEditField
      function MinVideoTimeEditFieldValueChanged(app, event)
         app.settings.reward.minVideoTime_s = app.MinVideoTimeEditField.Value;
         app.isDirty = true;                                  
      end

      % Button pushed function: AddButton
      function AddButtonPushed(app, event)
         [f, p] = uigetfile('*.*', 'Select a video', app.videoFolder, 'MultiSelect', 'on');
         if ~isequal(f, 0)
            if ischar(f)
               f = {f};
            end
            toAdd = cell(size(f));
            for k = 1:length(f)
               toAdd{k} = fullfile(p, f{k});
            end
            app.settings.reward.videoPlaylist = [app.settings.reward.videoPlaylist toAdd];
            app.VideoListBox.Items = app.settings.reward.videoPlaylist;
            app.VideoListBox.ItemsData = 1:length(app.settings.reward.videoPlaylist);
            app.isDirty = true;
            app.videoChanged = true;
         end

      end

      % Button pushed function: DeleteButton
      function DeleteButtonPushed(app, event)
         idx = app.VideoListBox.Value;
         if isempty(idx), return; end
         
         ikeep = app.VideoListBox.ItemsData ~= idx;
         app.settings.reward.videoPlaylist = app.settings.reward.videoPlaylist(ikeep);
         app.VideoListBox.Items = app.settings.reward.videoPlaylist;
         app.VideoListBox.ItemsData = 1:length(app.settings.reward.videoPlaylist);
         app.isDirty = true;
         app.videoChanged = true;
      end

      % Button pushed function: UpButton
      function UpButtonPushed(app, event)
         idx = app.VideoListBox.Value;
         if isempty(idx) || idx==1, return; end
         
         tmp = app.settings.reward.videoPlaylist{idx};
         app.settings.reward.videoPlaylist{idx} = app.settings.reward.videoPlaylist{idx - 1};
         app.settings.reward.videoPlaylist{idx-1} = tmp;
         app.VideoListBox.Items = app.settings.reward.videoPlaylist;

         app.VideoListBox.Value = idx - 1;
         app.isDirty = true;
         app.videoChanged = true;
      end

      % Button pushed function: DownButton
      function DownButtonPushed(app, event)
         idx = app.VideoListBox.Value;
         if isempty(idx) || idx==length(app.settings.reward.videoPlaylist), return; end
         
         tmp = app.settings.reward.videoPlaylist{idx};
         app.settings.reward.videoPlaylist{idx} = app.settings.reward.videoPlaylist{idx + 1};
         app.settings.reward.videoPlaylist{idx+1} = tmp;
         app.VideoListBox.Items = app.settings.reward.videoPlaylist;

         app.VideoListBox.Value = idx + 1;         
         app.isDirty = true;
         app.videoChanged = true;         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Settings';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.CancelButton = uibutton(app.UIFigure, 'push');
         app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
         app.CancelButton.Text = 'Cancel';
         app.StimulusPanel = uipanel(app.UIFigure);
         app.StimulusPanel.Title = 'Stimulus';
         app.RepeatspertrialEditFieldLabel = uilabel(app.StimulusPanel);
         app.RepeatspertrialEditFieldLabel.Text = 'Repeats per trial';
         app.RepeatsEditField = uieditfield(app.StimulusPanel, 'numeric');
         app.RepeatsEditField.Limits = [1 Inf];
         app.RepeatsEditField.RoundFractionalValues = 'on';
         app.RepeatsEditField.ValueDisplayFormat = '%d';
         app.RepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatsEditFieldValueChanged, true);
         app.RepeatsEditField.Value = 1;
         app.IntervalsEditFieldLabel = uilabel(app.StimulusPanel);
         app.IntervalsEditFieldLabel.Text = 'Interval (s)';
         app.IntervalEditField = uieditfield(app.StimulusPanel, 'numeric');
         app.IntervalEditField.Limits = [0 Inf];
         app.IntervalEditField.ValueDisplayFormat = '%11.1g';
         app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
         app.TrialdurationsLabel = uilabel(app.StimulusPanel);
         app.TrialdurationsLabel.Text = 'Trial duration (s)';
         app.TrialDurEditField = uieditfield(app.StimulusPanel, 'numeric');
         app.TrialDurEditField.Limits = [0 Inf];
         app.TrialDurEditField.ValueChangedFcn = createCallbackFcn(app, @TrialDurEditFieldValueChanged, true);
         app.PhasevaluesPanel = uipanel(app.UIFigure);
         app.PhasevaluesPanel.Title = 'Phase values';
         app.TrainingEditFieldLabel = uilabel(app.PhasevaluesPanel);
         app.TrainingEditFieldLabel.Text = 'Training';
         app.TrainingEditField = uieditfield(app.PhasevaluesPanel, 'numeric');
         app.TrainingEditField.ValueChangedFcn = createCallbackFcn(app, @TrainingEditFieldValueChanged, true);
         app.EasyEditFieldLabel = uilabel(app.PhasevaluesPanel);
         app.EasyEditFieldLabel.Text = 'Easy';
         app.EasyEditField = uieditfield(app.PhasevaluesPanel, 'numeric');
         app.EasyEditField.ValueChangedFcn = createCallbackFcn(app, @EasyEditFieldValueChanged, true);
         app.TestEditFieldLabel = uilabel(app.PhasevaluesPanel);
         app.TestEditFieldLabel.Text = 'Test';
         app.TestEditField = uieditfield(app.PhasevaluesPanel, 'text');
         app.TestEditField.ValueChangedFcn = createCallbackFcn(app, @TestEditFieldValueChanged, true);
         app.RewardsPanel = uipanel(app.UIFigure);
         app.RewardsPanel.Title = 'Rewards';
         app.UsetoysCheckBox = uicheckbox(app.RewardsPanel);
         app.UsetoysCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsetoysCheckBoxValueChanged, true);
         app.UsetoysCheckBox.Text = 'Use toys';
         app.MinvideotimesEditFieldLabel = uilabel(app.RewardsPanel);
         app.MinvideotimesEditFieldLabel.Text = 'Min video time (s)';
         app.MinVideoTimeEditField = uieditfield(app.RewardsPanel, 'numeric');
         app.MinVideoTimeEditField.Limits = [0 Inf];
         app.MinVideoTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MinVideoTimeEditFieldValueChanged, true);
         app.UsevideoCheckBox = uicheckbox(app.RewardsPanel);
         app.UsevideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsevideoCheckBoxValueChanged, true);
         app.UsevideoCheckBox.Text = 'Use video';
         app.AddButton = uibutton(app.RewardsPanel, 'push');
         app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
         app.AddButton.Text = 'Add';
         app.VideoplaylistListBoxLabel = uilabel(app.RewardsPanel);
         app.VideoplaylistListBoxLabel.Text = 'Video playlist';
         app.VideoListBox = uilistbox(app.RewardsPanel);
         app.MintoytimesEditFieldLabel = uilabel(app.RewardsPanel);
         app.MintoytimesEditFieldLabel.Text = 'Min toy time (s)';
         app.MinToyTimeEditField = uieditfield(app.RewardsPanel, 'numeric');
         app.MinToyTimeEditField.Limits = [0 Inf];
         app.MinToyTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MinToyTimeEditFieldValueChanged, true);
         app.DeleteButton = uibutton(app.RewardsPanel, 'push');
         app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
         app.DeleteButton.Text = 'Delete';
         app.UpButton = uibutton(app.RewardsPanel, 'push');
         app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
         app.UpButton.Text = 'Up';
         app.DownButton = uibutton(app.RewardsPanel, 'push');
         app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
         app.DownButton.Text = 'Down';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPPSettings(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end