classdef OPPVoiceSettings < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure        matlab.ui.Figure
      SpeakButton     matlab.ui.control.Button
      TextToSayField  matlab.ui.control.EditField
      TestButton      matlab.ui.control.Button
      SpeechTable     matlab.ui.control.Table
      useCheckBox     matlab.ui.control.CheckBox
      CancelButton    matlab.ui.control.Button
      OKButton        matlab.ui.control.Button
      banner          matlab.ui.control.Image
   end

   
   properties (Access = private)
      Settings % Description
      ChangeFcn;
      result = 'cancel';
      hvoice
      hstream
   end
   
   methods (Access = private)

   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, centerOn, settings, voice, audioStream, changeFcn)
         app.Settings = settings;
         app.hvoice = voice;
         app.hstream = audioStream;
         app.ChangeFcn = changeFcn;

         pos = app.UIFigure.Position;
         pos(1) = centerOn(1) + centerOn(3)/2 - pos(3)/2;
         pos(2) = centerOn(2) + centerOn(4)/2 - pos(4)/2;
         app.UIFigure.Position = pos;

         app.useCheckBox.Value = app.Settings.useVoice;

         data = cell(8, 2);
         data{1, 1} = 'Trial start';      data{1, 2} = char(app.Settings.trialStart);
         data{2, 1} = 'Training phase';   data{2, 2} = char(app.Settings.trainingPhase);
         data{3, 1} = 'Test phase';       data{3, 2} = char(app.Settings.testPhase);
         data{4, 1} = 'Bounce back';      data{4, 2} = char(app.Settings.bounceBack);
         data{5, 1} = 'Hit';              data{5, 2} = char(app.Settings.hit);
         data{6, 1} = 'Miss';             data{6, 2} = char(app.Settings.miss);
         data{7, 1} = 'False alarm';      data{7, 2} = char(app.Settings.falseAlarm);
         data{8, 1} = 'Withhold';         data{8, 2} = char(app.Settings.withhold);

         app.SpeechTable.Data = data;

         drawnow;
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if isequal(app.result, 'ok') && ~isempty(app.ChangeFcn)
            app.ChangeFcn(app.Settings);
         end
         uiresume(app.UIFigure);
         delete(app)
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         app.result = 'ok';
         close(app.UIFigure);         
      end

      % Button pushed function: CancelButton
      function CancelButtonPushed(app, event)
         app.result = 'cancel';
         close(app.UIFigure);
      end

      % Button pushed function: TestButton
      function TestButtonPushed(app, event)
         if ~isempty(app.SpeechTable.Selection)
            % app.hvoice.Speak(app.SpeechTable.Data{app.SpeechTable.Selection, 2});
            y = app.hvoice.Render(app.SpeechTable.Data{app.SpeechTable.Selection, 2});
            app.hstream.SendTTS(single(y));
         end
      end

      % Cell edit callback: SpeechTable
      function SpeechTableCellEdit(app, event)
         item = event.Indices(1);
         newData = event.NewData;

         if item == 1
            app.Settings.trialStart = newData;
         elseif item == 2
            app.Settings.trainingPhase = newData;
         elseif item == 3
            app.Settings.testPhase = newData;
         elseif item == 4
            app.Settings.bounceBack = newData;
         elseif item == 5
            app.Settings.hit = newData;
         elseif item == 6
            app.Settings.miss = newData;
         elseif item == 7
            app.Settings.falseAlarm = newData;
         elseif item == 8
            app.Settings.withhold = newData;
         end
      end

      % Button pushed function: SpeakButton
      function SpeakButtonPushed(app, event)
         if ~isempty(app.TextToSayField.Value)
            y = app.hvoice.Render(app.TextToSayField.Value);
            app.hstream.SendTTS(single(y));
         end
      end

      % Value changed function: useCheckBox
      function useCheckBoxValueChanged(app, event)
         app.Settings.useVoice = app.useCheckBox.Value;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Voice settings';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.CancelButton = uibutton(app.UIFigure, 'push');
         app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
         app.CancelButton.Text = 'Cancel';
         app.useCheckBox = uicheckbox(app.UIFigure);
         app.useCheckBox.ValueChangedFcn = createCallbackFcn(app, @useCheckBoxValueChanged, true);
         app.useCheckBox.Text = 'Use voice cues ';
         app.SpeechTable = uitable(app.UIFigure);
         app.SpeechTable.ColumnName = {'Event'; 'Speech'};
         app.SpeechTable.RowName = {};
         app.SpeechTable.SelectionType = 'row';
         app.SpeechTable.ColumnEditable = [false true];
         app.SpeechTable.CellEditCallback = createCallbackFcn(app, @SpeechTableCellEdit, true);
         app.SpeechTable.Multiselect = 'off';
         app.TestButton = uibutton(app.UIFigure, 'push');
         app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
         app.TestButton.Text = 'Test';
         app.TextToSayField = uieditfield(app.UIFigure, 'text');
         app.SpeakButton = uibutton(app.UIFigure, 'push');
         app.SpeakButton.ButtonPushedFcn = createCallbackFcn(app, @SpeakButtonPushed, true);
         app.SpeakButton.Text = 'Speak';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPPVoiceSettings(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end