classdef OPP_AcousticCalibration < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                     matlab.ui.Figure
      MeasureButton                matlab.ui.control.Button
      MeterButton                  matlab.ui.control.Button
      StopMeasureButton            matlab.ui.control.Button
      Panel                        matlab.ui.container.Panel
      SelectIntegerStepsButton     matlab.ui.control.Button
      UITable                      matlab.ui.control.Table
      ExitButton                   matlab.ui.control.Button
      SaveButton                   matlab.ui.control.Button
      PlayButton                   matlab.ui.control.Button
      AttenEditField               matlab.ui.control.NumericEditField
      AttenuationdBEditFieldLabel  matlab.ui.control.Label
      StopButton                   matlab.ui.control.Button
   end

   
   properties (Access = private)
      calPath % Description
      attenuation % Description
      isDirty % Description
      hStreamer
      hMixer
      soundMeter = [];
      stopMeasurement
      Efreq
      freq
      calData
      nstep = 8;
      calFig = [];
   end
   
   methods (Access = private)
      
      function LoadCalibration(app)
         load(app.calPath, 'opp_cal');
         if exist('opp_cal', 'var')
            cal = opp_cal;
         else
            cal.FreqHz = app.freq;
            cal.SPL_Vrms = NaN*ones(size(app.freq));
         end

         Lmax = interp1(cal.FreqHz, cal.SPL_Vrms, app.freq);

         app.calData.freq = app.freq;
         app.calData.savedMax = Lmax;
         app.calData.newMax = NaN*ones(size(Lmax));
      end

      %--------------------------------------------------------------------
      function PlotCalibration(app)
         if ~isempty(app.calFig)
            figure(app.calFig);
         else
            app.calFig = findobj('Type', 'Figure', 'Name', 'Acoustic calibration');            
            if isempty(app.calFig)
               app.calFig = figure('Name', 'Acoustic calibration', 'NumberTitle', 'off');
            end
         end

         clf(app.calFig);
         hax = axes();
         cmap = get(hax, 'ColorOrder');
         hold(hax, 'on');
         plot(hax, app.calData.freq, app.calData.savedMax, '.', 'Color', cmap(1,:));
         h = plot(hax, app.calData.freq(1:app.nstep:end), app.calData.savedMax(1:app.nstep:end), 'o', 'Color', cmap(1,:));
         set(h, 'MarkerFaceColor', get(h, 'Color'));
         plot(hax, app.calData.freq, app.calData.newMax, '.', 'Color', cmap(2,:));

         set(hax, 'XScale', 'log');
         xlabel(hax, 'Frequency (Hz)');
         ylabel(hax, 'Max dB SPL');

         set(hax, 'XLim', [min(app.Efreq)/2^0.25 max(app.Efreq)*2^0.25])
         set(hax, 'XTick', app.Efreq);
         set(hax, 'XMinorTick', 'off');

                  grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
      end

      %--------------------------------------------------------------------
      function SaveData(app)
         opp_cal.FreqHz = app.calData.freq;
         opp_cal.SPL_Vrms = app.calData.savedMax;
         
         ifilt = ~isnan(app.calData.newMax);
         opp_cal.SPL_Vrms(ifilt) = app.calData.newMax(ifilt);
         save(app.calPath, 'opp_cal', '-append');

         app.calData.savedMax = opp_cal.SPL_Vrms;
         app.calData.newMax = NaN*ones(size(app.calData.savedMax));
         app.isDirty = false;

         app.ShowTable();
         app.PlotCalibration();

         app.isDirty = false;
      end

      %--------------------------------------------------------------------
      function ShowTable(app)
         app.UITable.RowName = {};
         d = table(num2str(app.freq, '%d'), num2str(app.calData.savedMax, '%.1f'), cell(size(app.freq)), cell(size(app.freq)), 'VariableNames', {'Freq', 'Old', 'New', 'Measured'});
         app.UITable.Data = d;       
         app.UITable.addStyle(uistyle('HorizontalAlignment', 'right'), 'table', '');
         app.UITable.addStyle(uistyle('BackgroundColor', [0.85 0.85 0.95]), 'row', 1:app.nstep:length(app.freq));
      end

      %--------------------------------------------------------------------
      function AcquireData(app)
         ifreq = app.UITable.Selection;
         if isempty(ifreq)
            return;
         end

         app.stopMeasurement = false;
         app.PlayButton.Enable = false;
         app.StopMeasureButton.Enable = true;
         app.MeasureButton.Visible = false;

         Fs = 48000;
         % T = 5;
         % npts = round(Fs * T);
         npts = double(app.hStreamer.LoopLengthSamples);
         t = (0:npts-1) / Fs;

         try
            for kf = 1:length(ifreq)

               app.UITable.Selection = ifreq(kf);
               f = str2double(app.UITable.Data.Freq(ifreq(kf),:));

               % y = app.CreateGate(Fs, npts, 20) .* sin(2*pi*t*f);
               y = sin(2*pi*t*f);
               y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

               % Set both modes so it doesn't matter what mode the OPP was in
               % when we came here
               app.hStreamer.SetStimulusSet('Test', y, y);
               app.hStreamer.SetStimulusSet('Training', y, y);

               % app.hStreamer.Start();
               app.hStreamer.WaitForLatch(0.2);
               app.hMixer.EnableSubjectAudioStream(true);

               value = app.soundMeter.AcquireValue(f);
               app.UITable.Data.Measured(ifreq(kf)) = {num2str(value, '%.1f')};
               app.UITable.Data.New(ifreq(kf)) = {num2str(value - app.attenuation, '%.1f')};
               app.calData.newMax(ifreq(kf)) = value - app.attenuation;
               app.PlotCalibration();
               app.isDirty = true;

               app.hMixer.EnableSubjectAudioStream(false);
               % app.hStreamer.Stop();

               if app.stopMeasurement
                  break;
               end
            end
         catch ex
            errordlg(ex.message);
         end

         app.PlayButton.Enable = true;
         app.MeasureButton.Visible = true;
         app.UITable.Enable = 'on';
      end

     %--------------------------------------------------------------------
     function G = CreateGate(~, Fs, numPoints, ramplen)
         sinePeriod_s = 2 * ramplen / 1000;
         npts = sinePeriod_s * Fs;
         sineFrequency = 1 / sinePeriod_s;

         sineBuffer = -cos(2*pi*sineFrequency*(0:npts-1)/Fs);
         sineBuffer = (sineBuffer + 1)/2;

         %  Split the sinusoid in half and fill center with ones
         numOnes = numPoints - length(sineBuffer);
         G = [sineBuffer(1:round(npts/2)) ones(1,numOnes) sineBuffer((round(npts/2)+1):end)];
     end

      %--------------------------------------------------------------------
      function success = WaitForStreamState(app, state)
         success = false;

         startTime = tic();
         while toc(startTime) < 5
            isStreaming = app.hStreamer.IsStreaming;
            if isequal(state, 'running') && isStreaming
               success = true;
               break;
            end
            if isequal(state, 'stopped') && ~isStreaming
               success = true;
               break;
            end
            lastError = char(app.hStreamer.LastError);
            if ~isempty(lastError)
               error(lastError);
               success = false;
               break;
            end
            pause(0.1);
         end
      end

   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, freq, calPath, streamer, mixer)
         app.Efreq = freq(:);
         app.calPath = calPath;
         app.hStreamer = streamer;
         app.hMixer = mixer;

         % Subsample electrode frequency spacing
         ifreq = (0:app.nstep-1) / app.nstep;
         df = diff(app.Efreq);
         app.freq = app.Efreq(1:end-1)' + (ifreq' * df');
         app.freq = round([app.freq(:); app.Efreq(end)]);

         % Restore attenuation
         app.attenuation = getsharedpref('OPP_AcousticCalibration', 'Attenuation', -20);
         app.AttenEditField.Value = app.attenuation;

         % Restore figure position
         pos = app.UIFigure.Position;
         monitorPos = get(0, 'MonitorPositions');
         iprimary = find(monitorPos(:, 1) == 1);
         x = 0.5*(monitorPos(iprimary, 3) - pos(3));
         y = 0.5*(monitorPos(iprimary, 4) - pos(4));
%          rmpref('APTC_AcousticCalibration', 'Position');
         origin = getsharedpref('OPP_AcousticCalibration', 'Position', [x y pos(3:4)]);
         if ~isempty(origin)
            pos(1:2) = origin(1:2);
            app.UIFigure.Position = pos;
         end

         app.LoadCalibration();
         app.ShowTable();
         app.PlotCalibration();
         
         app.isDirty = false;
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
        try
            if app.isDirty
                response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
                if isequal(response, 'Cancel')
                    return;
                elseif isequal(response, 'Save')
                    app.SaveData();
                end
            end

            if ~isempty(app.soundMeter)
                app.soundMeter.Quit();
            end

            pos = app.UIFigure.Position;
            setsharedpref('OPP_AcousticCalibration', 'Position', pos(1:2));
            setsharedpref('OPP_AcousticCalibration', 'Attenuation', app.attenuation);

        catch
        end

        uiresume(app.UIFigure);

        delete(app)
      end

      % Button pushed function: ExitButton
      function ExitButtonPushed(app, event)
         close(app.UIFigure);
      end

      % Value changed function: AttenEditField
      function AttenEditFieldValueChanged(app, event)
         app.attenuation = app.AttenEditField.Value;         
      end

      % Button pushed function: PlayButton
      function PlayButtonPushed(app, event)
         try
             ifreq = app.UITable.Selection;
             if isempty(ifreq)
                 return;
             end
             app.PlayButton.Enable = 'off';

             f = str2double(app.UITable.Data.Freq(ifreq,:));

             Fs = 48000;
             % T = 5;
             % npts = round(Fs * T);
             npts = double(app.hStreamer.LoopLengthSamples);
             t = (0:npts-1) / Fs;
             % y = app.CreateGate(Fs, npts, 20) .* sin(2*pi*t*f);
             y = sin(2*pi*t*f);
             y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

             % app.hStreamer.Stop();
             % app.WaitForStreamState('stopped');
             % app.hStreamer.SetConfig(npts);

             % Set both modes so it doesn't matter what mode the OPP was in
             % when we came here
             app.hStreamer.SetStimulusSet('Test', y, y);
             app.hStreamer.SetStimulusSet('Training', y, y);

             app.UITable.Enable = 'off';

             app.PlayButton.Visible = false;
             % app.hStreamer.Start();
             app.hStreamer.WaitForLatch(0.2);
             app.hMixer.EnableSubjectAudioStream(true);
         catch ex
             errordlg(ex.message);
             app.PlayButton.Visible = true;
             app.UITable.Enable = 'on';
         end
         app.PlayButton.Enable = 'on';
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.hMixer.EnableSubjectAudioStream(false);
         % app.hStreamer.Stop();
         % app.WaitForStreamState('stopped');
         app.UITable.Enable = 'on';
         app.PlayButton.Visible = true;

      end

      % Cell edit callback: UITable
      function UITableCellEdit(app, event)
         indices = event.Indices;
         newData = event.NewData;
         newSPL = newData - app.attenuation;
         app.UITable.Data.New(indices(1)) = {newSPL};
         app.isDirty = true;
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)
         app.SaveData();
      end

      % Button pushed function: MeterButton
      function MeterButtonPushed(app, event)
         app.soundMeter = Sound_Meter();
      end

      % Button pushed function: MeasureButton
      function MeasureButtonPushed(app, event)
         app.AcquireData();
      end

      % Button pushed function: StopMeasureButton
      function StopMeasureButtonPushed(app, event)
         app.StopMeasureButton.Enable = false;
         app.stopMeasurement = true;
      end

      % Button pushed function: SelectIntegerStepsButton
      function SelectIntegerStepsButtonPushed(app, event)
         app.UITable.Selection = 1 : app.nstep : length(app.UITable.Data.Freq);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Acoustic Calibration';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Text = 'Stop';
         app.AttenuationdBEditFieldLabel = uilabel(app.UIFigure);
         app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
         app.AttenEditField = uieditfield(app.UIFigure, 'numeric');
         app.AttenEditField.Limits = [-Inf -3];
         app.AttenEditField.ValueChangedFcn = createCallbackFcn(app, @AttenEditFieldValueChanged, true);
         app.AttenEditField.Value = -3;
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
         app.PlayButton.Text = 'Play';
         app.SaveButton = uibutton(app.UIFigure, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.ExitButton = uibutton(app.UIFigure, 'push');
         app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
         app.ExitButton.Text = 'Exit';
         app.Panel = uipanel(app.UIFigure);
         app.UITable = uitable(app.Panel);
         app.UITable.ColumnName = {'Freq'; 'Old SPL'; 'New SPL'; 'Measured'};
         app.UITable.RowName = {''; ''};
         app.UITable.SelectionType = 'row';
         app.UITable.ColumnEditable = [false false false true];
         app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
         app.SelectIntegerStepsButton = uibutton(app.Panel, 'push');
         app.SelectIntegerStepsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectIntegerStepsButtonPushed, true);
         app.SelectIntegerStepsButton.Text = 'Select integer steps only';
         app.StopMeasureButton = uibutton(app.UIFigure, 'push');
         app.StopMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasureButtonPushed, true);
         app.StopMeasureButton.Text = 'Stop';
         app.MeterButton = uibutton(app.UIFigure, 'push');
         app.MeterButton.ButtonPushedFcn = createCallbackFcn(app, @MeterButtonPushed, true);
         app.MeterButton.Text = 'Meter';
         app.MeasureButton = uibutton(app.UIFigure, 'push');
         app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
         app.MeasureButton.Text = 'Measure selected';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPP_AcousticCalibration(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end