classdef OntrakTest < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure         matlab.ui.Figure
      ontrakImage      matlab.ui.control.Image
      StatusEditField  matlab.ui.control.EditField
      connectButton    matlab.ui.control.Button
      ApplyButton      matlab.ui.control.Button
      banner           matlab.ui.control.Image
      MotorCheckBox    matlab.ui.control.CheckBox
      Toy2CheckBox     matlab.ui.control.CheckBox
      Toy1CheckBox     matlab.ui.control.CheckBox
   end

   
   properties (Access = private)
      hOntrak % Description
      deleteHandleWhenFinished = true;
   end
   
   methods (Access = private)
      
      function success = InitializeOntrak(app)
         success = false;
         try
            app.hOntrak = app.hOntrak.Initialize();
            app.hOntrak.GetState();

            app.StatusEditField.FontColor = [0 0 0];
            app.StatusEditField.Value = 'Connected to Ontrak device.';

            app.Toy1CheckBox.Enable = true;
            app.Toy2CheckBox.Enable = true;
            app.MotorCheckBox.Enable = true;
            app.ApplyButton.Enable = true;
            app.connectButton.Enable = false;

            success = true;
         catch
            app.StatusEditField.FontColor = [0.75 0 0];
            app.StatusEditField.Value = 'Failed to connect. Is Ontrak device connected?';
            app.connectButton.Enable = true;
         end
      end
      
      %--------------------------------------------------------------------
      function ReadOntrakState(app)
         [toy1, toy2, motor] = app.hOntrak.GetState();
         app.Toy1CheckBox.Value = toy1;
         app.Toy2CheckBox.Value = toy2;
         app.MotorCheckBox.Value = motor;
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, ontrakHandle)
         app.connectButton.Enable = false;
         app.Toy1CheckBox.Enable = false;
         app.Toy2CheckBox.Enable = false;
         app.MotorCheckBox.Enable = false;
         app.ApplyButton.Enable = false;

         % Restore figure position
         origin = getpref('OntrakTest', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end

         if nargin > 1
            app.hOntrak = ontrakHandle;
            app.deleteHandleWhenFinished = false;
         else
            try
               app.hOntrak = Ontrak();
            catch
               app.StatusEditField.FontColor = [0.75 0 0];
               app.StatusEditField.Value = 'Failed to open Ontrak DLL.';
            end
         end
         
         initialized = app.InitializeOntrak();
         if initialized
            app.ReadOntrakState();
         end
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if app.deleteHandleWhenFinished
            delete(app.hOntrak);
         end
         
         uiresume(app.UIFigure);

         pos = app.UIFigure.Position;
         setpref('OntrakTest', 'Position', pos(1:2));
         delete(app)
      end

      % Button pushed function: connectButton
      function connectButtonPushed(app, event)
         initialized = app.InitializeOntrak();
         if initialized
            app.ReadOntrakState();
         end
      end

      % Button pushed function: ApplyButton
      function ApplyButtonPushed(app, event)
         try
            app.hOntrak.TurnOn(app.Toy1CheckBox.Value, app.Toy2CheckBox.Value, app.MotorCheckBox.Value);
         catch ex
            msgbox(['Error writing to Ontrak device: ' ex.message], 'Ontrak Test', 'error');
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Test Ontrak ADU';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.Toy1CheckBox = uicheckbox(app.UIFigure);
         app.Toy1CheckBox.Text = 'Toy 1';
         app.Toy2CheckBox = uicheckbox(app.UIFigure);
         app.Toy2CheckBox.Text = 'Toy 2';
         app.MotorCheckBox = uicheckbox(app.UIFigure);
         app.MotorCheckBox.Text = 'Motor';
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.ApplyButton = uibutton(app.UIFigure, 'push');
         app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
         app.ApplyButton.Icon = 'arrow_right_green.png';
         app.ApplyButton.Text = 'Apply';
         app.connectButton = uibutton(app.UIFigure, 'push');
         app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
         app.connectButton.Text = 'connect';
         app.StatusEditField = uieditfield(app.UIFigure, 'text');
         app.StatusEditField.Editable = 'off';
         app.ontrakImage = uiimage(app.UIFigure);
         app.ontrakImage.ImageSource = 'Ontrak.png';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OntrakTest(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end