classdef VLCTest < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure       matlab.ui.Figure
      VideoDropDown  matlab.ui.control.DropDown
      PauseButton    matlab.ui.control.Button
      vlcImage       matlab.ui.control.Image
      banner         matlab.ui.control.Image
      PlayButton     matlab.ui.control.Button
      StopButton     matlab.ui.control.Button
   end

   
   properties (Access = private)
      hVLC
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, vlc_handle, videoList)
         app.hVLC = vlc_handle;

         % Restore figure position
         origin = getsharedpref('VLCTest', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end

         app.VideoDropDown.Items = videoList;

         status = app.hVLC.Status();
         if strcmpi(status, 'playing')
            app.PlayButton.Enable = false;
         elseif strcmpi(status, 'paused')
            app.PauseButton.Enable = false;
         elseif strcmpi(status, 'stopped')
            app.StopButton.Enable = false;
         end

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         uiresume(app.UIFigure);

         pos = app.UIFigure.Position;
         setsharedpref('VLCTest', 'Position', pos(1:2));
         delete(app)         
      end

      % Button pushed function: PlayButton
      function PlayButtonPushed(app, event)
         app.hVLC.play();
         app.PlayButton.Enable = false;
         app.PauseButton.Enable = true;
         app.StopButton.Enable = true;
      end

      % Button pushed function: PauseButton
      function PauseButtonPushed(app, event)
         app.hVLC.pause();         
         app.PlayButton.Enable = true;
         app.PauseButton.Enable = false;
         app.StopButton.Enable = true;
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.hVLC.stop();         
         app.PlayButton.Enable = true;
         app.PauseButton.Enable = true;
         app.StopButton.Enable = false;
      end

      % Value changed function: VideoDropDown
      function VideoDropDownValueChanged(app, event)
         value = app.VideoDropDown.Value;
         app.hVLC.play(value);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'VLC Test';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Icon = 'media_stop.png';
         app.StopButton.Text = {''; ''};
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
         app.PlayButton.Icon = 'media_play.png';
         app.PlayButton.Text = {''; ''};
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.vlcImage = uiimage(app.UIFigure);
         app.vlcImage.ImageSource = 'VLC.png';
         app.PauseButton = uibutton(app.UIFigure, 'push');
         app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
         app.PauseButton.Icon = 'media_pause.png';
         app.PauseButton.Text = {''; ''};
         app.VideoDropDown = uidropdown(app.UIFigure);
         app.VideoDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoDropDownValueChanged, true);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = VLCTest(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end